classdef MedImagePro < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        Tab                            matlab.ui.container.Tab
        Image                          matlab.ui.control.Image
        txt_Stats                      matlab.ui.control.TextArea
        Label                          matlab.ui.control.Label
        MedicalImageSegmentationPanel  matlab.ui.container.Panel
        GridLayout                     matlab.ui.container.GridLayout
        ParameterSettingsPanel         matlab.ui.container.Panel
        thrFixed0109EditField          matlab.ui.control.NumericEditField
        thrFixed0109EditFieldLabel     matlab.ui.control.Label
        biasreg00001001EditField       matlab.ui.control.NumericEditField
        biasreg00001001EditFieldLabel  matlab.ui.control.Label
        biasfwhm30120EditField         matlab.ui.control.NumericEditField
        biasfwhm30120EditFieldLabel    matlab.ui.control.Label
        maxiter10200EditField          matlab.ui.control.NumericEditField
        maxiter10200EditFieldLabel     matlab.ui.control.Label
        kappa0108EditField             matlab.ui.control.NumericEditField
        kappa0108EditFieldLabel        matlab.ui.control.Label
        EvaluationMetricsPanel         matlab.ui.container.Panel
        UITable2                       matlab.ui.control.Table
        AlgorithmConfigurationButtonGroup  matlab.ui.container.ButtonGroup
        AccuracyValidationButton       matlab.ui.control.Button
        StartSegmentationButton        matlab.ui.control.Button
        LSTLesionGrowthT1FLAIRButton   matlab.ui.control.RadioButton
        LSTLesionPredictionFLAIRonlyButton  matlab.ui.control.RadioButton
        SPM12TissueSegmentationT1onlyButton  matlab.ui.control.RadioButton
        DataLoadingPanel               matlab.ui.container.Panel
        NotAvailableLabel_4            matlab.ui.control.Label
        Button_9                       matlab.ui.control.Button
        Label_5                        matlab.ui.control.Label
        NotAvailableLabel_3            matlab.ui.control.Label
        NotAvailableLabel_2            matlab.ui.control.Label
        NotAvailableLabel              matlab.ui.control.Label
        Button_8                       matlab.ui.control.Button
        Button_7                       matlab.ui.control.Button
        GTLabel                        matlab.ui.control.Label
        FLAIRLabel                     matlab.ui.control.Label
        T1wLabel                       matlab.ui.control.Label
        Button_6                       matlab.ui.control.Button
        Panel_2                        matlab.ui.container.Panel
        Button_5                       matlab.ui.control.Button
        UITable                        matlab.ui.control.Table
        Button_4                       matlab.ui.control.Button
        Button_3                       matlab.ui.control.Button
        lbl_Status                     matlab.ui.control.Label
        Panel                          matlab.ui.container.Panel
        Slider                         matlab.ui.control.Slider
        SliderLabel                    matlab.ui.control.Label
        slider_Contrast                matlab.ui.control.Slider
        Label_4                        matlab.ui.control.Label
        slider_Slice                   matlab.ui.control.Slider
        Label_3                        matlab.ui.control.Label
        dd_EdgeAlgorithm               matlab.ui.control.DropDown
        Label_2                        matlab.ui.control.Label
        lbl_SliceInfo                  matlab.ui.control.Label
        slider_Brightness              matlab.ui.control.Slider
        Panel_Display                  matlab.ui.container.Panel
        lbl_ProcessedTitle             matlab.ui.control.Label
        lbl_OriginalTitle              matlab.ui.control.Label
        ax_Original                    matlab.ui.control.UIAxes
        ax_Processed                   matlab.ui.control.UIAxes
        Panel_Control                  matlab.ui.container.Panel
        Button_2                       matlab.ui.control.Button
        NIfTIDICOMButton               matlab.ui.control.Button
        DICOMNIfTIButton               matlab.ui.control.Button
        NIfTIButton                    matlab.ui.control.Button
        Button                         matlab.ui.control.Button
        DICOMButton                    matlab.ui.control.Button
        btn_Save                       matlab.ui.control.Button
        btn_CLAHE                      matlab.ui.control.Button
        btn_HistEq                     matlab.ui.control.Button
        btn_ImportStandard             matlab.ui.control.Button
        btn_Reset                      matlab.ui.control.Button
        btn_GrayConvert                matlab.ui.control.Button
        btn_ImportDICOM                matlab.ui.control.Button
        ax_Histogram                   matlab.ui.control.UIAxes
        Tab_2                          matlab.ui.container.Tab
        Image2                         matlab.ui.control.Image
        Label_17                       matlab.ui.control.Label
        textarea_log                   matlab.ui.control.TextArea
        Label_16                       matlab.ui.control.Label
        CoregReslicePanel              matlab.ui.container.Panel
        edit_coreg_prefix              matlab.ui.control.EditField
        Label_19                       matlab.ui.control.Label
        dropdown_wrapping              matlab.ui.control.DropDown
        InterpolationLabel_4           matlab.ui.control.Label
        edit_hist_smooth1              matlab.ui.control.NumericEditField
        HistogramSmoothingLabel        matlab.ui.control.Label
        SourceImageLabel               matlab.ui.control.Label
        edit_source_image              matlab.ui.control.EditField
        edit_other_images              matlab.ui.control.EditField
        OtherImagesLabel               matlab.ui.control.Label
        dropdown_masking               matlab.ui.control.DropDown
        InterpolationLabel_3           matlab.ui.control.Label
        edit_reference_image           matlab.ui.control.EditField
        ReferenceImageLabel            matlab.ui.control.Label
        edit_spm_2                     matlab.ui.control.EditField
        SPM12Label_2                   matlab.ui.control.Label
        dropdown_coreg_interp          matlab.ui.control.DropDown
        InterpolationLabel_2           matlab.ui.control.Label
        edit_separation1               matlab.ui.control.NumericEditField
        SeparationLabel                matlab.ui.control.Label
        dropdown_obj_func              matlab.ui.control.DropDown
        ObjectiveFunctionLabel         matlab.ui.control.Label
        edit_hist_smooth2              matlab.ui.control.NumericEditField
        edit_separation2               matlab.ui.control.NumericEditField
        btn_run_coregister             matlab.ui.control.Button
        btn_browse_other_images        matlab.ui.control.Button
        btn_browse_source_image        matlab.ui.control.Button
        btn_browse_reference_image     matlab.ui.control.Button
        btn_browse_spm_2               matlab.ui.control.Button
        CoregEstimationPanel           matlab.ui.container.Panel
        edit_prefix                    matlab.ui.control.EditField
        Label_18                       matlab.ui.control.Label
        edit_voxel_size1               matlab.ui.control.NumericEditField
        VoxelsizesmmLabel              matlab.ui.control.Label
        dropdown_interp                matlab.ui.control.DropDown
        InterpolationLabel             matlab.ui.control.Label
        edit_sampling_dist             matlab.ui.control.NumericEditField
        Label_12                       matlab.ui.control.Label
        edit_smoothness                matlab.ui.control.NumericEditField
        Label_11                       matlab.ui.control.Label
        edit_bias_fwhm                 matlab.ui.control.NumericEditField
        FWHMmmLabel                    matlab.ui.control.Label
        edit_bias_reg                  matlab.ui.control.NumericEditField
        Label_10                       matlab.ui.control.Label
        dropdown_affine_reg            matlab.ui.control.DropDown
        Label_9                        matlab.ui.control.Label
        edit_tissue_prob_map           matlab.ui.control.EditField
        Label_8                        matlab.ui.control.Label
        edit_image_to_align            matlab.ui.control.EditField
        Label_7                        matlab.ui.control.Label
        edit_images_to_write           matlab.ui.control.EditField
        Label_6                        matlab.ui.control.Label
        edit_spm                       matlab.ui.control.EditField
        SPM12Label                     matlab.ui.control.Label
        edit_voxel_size3               matlab.ui.control.NumericEditField
        edit_voxel_size2               matlab.ui.control.NumericEditField
        btn_run_normalize              matlab.ui.control.Button
        btn_browse_tissue_prob_map     matlab.ui.control.Button
        btn_browse_images_to_write     matlab.ui.control.Button
        btn_browse_image_to_align      matlab.ui.control.Button
        btn_browse_spm                 matlab.ui.control.Button
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

    
    % Properties that store app data
    properties (Access = private)
        img_Original          % 原始图像数据 (double 0-1)
        img_Gray              % 灰度化后的基准图像 (double 0-1)
        img_Processed         % 处理后的图像数据 (double 0-1)
        isColorImage          % 是否为彩色图
        img_Volume            % 存储 3D DICOM 数据 (H x W x Slices)
        currentSliceIdx       % 当前切片索引
        dicomInfo
        isDICOMLoaded = false
        niftiInfo
        isNIfTILoaded = false
        niftiVolIdx = 1   % 4D NIfTI 当前 volume 索引（默认 1）
        isHistShown = false
        filePath
        T
        roiLabelSeparated   % uint32，连通域分离后的3D label
      roiBestSlice        % 最佳预览层
      roiCentroidMM       % N×3，质心(mm) [x y z] 相对mm
      roiVolumeMM3        % N×1，体积(mm^3)
      roiLabels           % N×1，ROI编号（1..N）
      roiIsOutlier        % N×1 logical，是否离群
      % ===== Seg UI: file paths =====
     t1Path    string = ""
     flairPath string = ""
     roiPath   string = ""
    % ===== Seg results =====
   segOutDir   string = ""      % 分割输出目录
   segMaskPath string = ""      % 输出mask路径（nii）
   segMaskVol  logical = []     % 预测mask体数据
   roiMaskVol  logical = []     % GT mask体数据（缓存）
   % ===== SPM12 tissue outputs =====
  segTissuePaths string = strings(0,1);   % 保存 SPM12 输出 c1~c6 路径
saveBaseDir string = ""
lstLgaOutputs string = strings(0,1);   % 本次 LGA 生成的全部文件路径
 % Normalise parameters
        affine_reg_options = {'mni', 'euro', 'none'};
        interp_options = {'Nearest Neighbour', 'Trilinear', '2nd Degree B-Spline', '3rd Degree B-Spline', '4th Degree B-Spline'};

        % Coregister parameters
        obj_func_options = {'mutual', 'nmi', 'cc'};
        wrapping_options = {'No wrap', 'Wrap'};
        masking_options = {'Dont mask images', 'Mask images'};
        Property33 % Description
    end
    
    % Methods that handle callbacks
    methods (Access = private)
      % --- 工具函数：统一写“系统日志/状态栏”，自动加时间戳 ---
  function setStatus(app, msg)
    ts = datestr(now, 'HH:MM:SS');
    app.lbl_Status.Text = sprintf('[%s] %s', ts, char(string(msg)));
    drawnow limitrate;
end


        % --- 工具函数：更新图像和统计 ---
        function updateDisplay(app)
            if isempty(app.img_Processed)
                return;
            end
            
            % 1. 显示处理后的图像
            imshow(app.img_Processed, 'Parent', app.ax_Processed);
            axis(app.ax_Processed, 'image');
            
        
           if app.isHistShown
             app.ax_Histogram.Visible = 'on';
             updateHistogram(app);
           else
            cla(app.ax_Histogram);
            app.ax_Histogram.Visible = 'off';
           end


            % 3. 计算并更新统计指标 (均值、方差、熵、峰值)
            % 确保用于计算的是灰度数据
            if size(app.img_Processed, 3) == 3
                imgForStats = rgb2gray(app.img_Processed);
            else
                imgForStats = app.img_Processed;
            end
            
            % 归一化到 0-255 计算更符合直觉
            imgUint8 = im2uint8(imgForStats);
            
            val_mean = mean2(imgUint8);
            val_std = std2(imgUint8);
            val_entropy = entropy(imgUint8);
            val_max = max(imgUint8(:));
            
            statsText = sprintf([...
                '均值 (Mean): %.2f\n', ...
                '标准差 (Std): %.2f\n', ...
                '熵 (Entropy): %.2f\n', ...
                '峰值 (Peak): %d'], ...
                val_mean, val_std, val_entropy, val_max);
            
dicomText = formatDicomInfoText(app);
niftiText = formatNiftiInfoText(app);

metaText = '';
if ~isempty(niftiText)
    metaText = niftiText;
elseif ~isempty(dicomText)
    metaText = dicomText;
end

if ~isempty(metaText)
    fullText = sprintf('%s\n【统计指标】\n%s', metaText, statsText);
else
    fullText = statsText;
end

app.txt_Stats.Value = splitlines(string(fullText));


        end

       function updateHistogram(app)
    if isempty(app.img_Processed), return; end

    % 取灰度数据
    if size(app.img_Processed, 3) == 3
        histData = rgb2gray(app.img_Processed);
    else
        histData = app.img_Processed;
    end

    % 清空旧图
    cla(app.ax_Histogram);

    % 判断是否近似二值图（比如 edge 输出 0/1）
    u = unique(histData(:));
    u = u(~isnan(u));
    isBinaryLike = (numel(u) <= 2);

    if isBinaryLike
        % 二值图：用 2 个 bin 更直观
        h = histogram(app.ax_Histogram, histData(:), ...
            'BinEdges', [-0.5 0.5 1.5], ...
            'Normalization', 'probability');
        app.ax_Histogram.XLim = [-0.1 1.1];
    else
        % 普通灰度图：固定范围到 [0,1]，避免边界挤压
        h = histogram(app.ax_Histogram, histData(:), 256, ...
            'BinLimits', [0 1], ...
            'Normalization', 'probability');
        app.ax_Histogram.XLim = [0 1];
    end

    % 外观
    h.FaceColor = [0.2 0.6 0.8];
    h.EdgeColor = 'none';

    % 坐标轴
    app.ax_Histogram.YLimMode = 'auto';
    title(app.ax_Histogram, '灰度分布直方图');
    grid(app.ax_Histogram, 'on');
end

        
        % --- 核心算法：应用亮度与对比度 ---
        % 公式：New = (Old - 0.5) * Contrast + 0.5 + Brightness
        function applyBrightnessContrast(app)
            if isempty(app.img_Gray), return; end
            
            b = app.slider_Brightness.Value / 100; % -1.0 到 1.0
            c = app.slider_Contrast.Value;         % 0.5 到 2.0
            
            % 基于 img_Gray (灰度基准) 进行计算，避免累积误差
            img = app.img_Gray;
            
            % 对比度调整 (围绕 0.5 中心拉伸)
            img = (img - 0.5) * c + 0.5;
            
            % 亮度调整
            img = img + b;
            
            % 截断到 [0, 1]
            img(img < 0) = 0;
            img(img > 1) = 1;
            
            app.img_Processed = img;
            updateDisplay(app);
            
            app.lbl_Status.Text = sprintf('状态：亮度 %.2f, 对比度 %.2f', b, c);
        end
       function dicomText = formatDicomInfoText(app)
    dicomText = '';

    if ~app.isDICOMLoaded || isempty(app.dicomInfo)
        return;
    end

    info = app.dicomInfo;
    getf = @(fn, def) localFieldToChar(info, fn, def);

    % --- 常用信息 ---
    patientName = getf('PatientName', 'Unknown');
    patientID   = getf('PatientID', 'Unknown');
    patientSex  = getf('PatientSex', 'Unknown');
    patientAge  = getf('PatientAge', 'Unknown');

    modality     = getf('Modality', 'Unknown');
    manufacturer = getf('Manufacturer', 'Unknown');
    institution  = getf('InstitutionName', 'Unknown');

    studyDesc  = getf('StudyDescription', 'Unknown');
    seriesDesc = getf('SeriesDescription', 'Unknown');
    protocol   = getf('ProtocolName', 'Unknown');

    studyDate = getf('StudyDate', 'Unknown');
    studyTime = getf('StudyTime', 'Unknown');
    seriesNum = getf('SeriesNumber', 'Unknown');
    instNum   = getf('InstanceNumber', 'Unknown');

    % --- 尺寸/像素 ---
    rows = getf('Rows', 'Unknown');
    cols = getf('Columns', 'Unknown');

    px = 'Unknown';
    if isfield(info,'PixelSpacing') && ~isempty(info.PixelSpacing)
        try
            px = sprintf('%.4f\\%.4f', info.PixelSpacing(1), info.PixelSpacing(2));
        catch
            px = 'Unknown';
        end
    end

    sliceThickness = getf('SliceThickness', 'Unknown');
    spacingBetweenSlices = getf('SpacingBetweenSlices', 'Unknown');

    % --- 图像属性 ---
    photo = getf('PhotometricInterpretation', 'Unknown');
    spp   = getf('SamplesPerPixel', 'Unknown');
    bitsA = getf('BitsAllocated', 'Unknown');
    bitsS = getf('BitsStored', 'Unknown');
    highB = getf('HighBit', 'Unknown');

    % --- 窗宽窗位/Rescale（常见） ---
    rescaleSlope     = getf('RescaleSlope', 'Unknown');
    rescaleIntercept = getf('RescaleIntercept', 'Unknown');
    windowCenter     = getf('WindowCenter', 'Unknown');
    windowWidth      = getf('WindowWidth', 'Unknown');

    % --- 空间信息 ---
    bodyPart = getf('BodyPartExamined', 'Unknown');
    position = getf('PatientPosition', 'Unknown');
    ipp      = getf('ImagePositionPatient', 'Unknown');
    iop      = getf('ImageOrientationPatient', 'Unknown');
    sliceLoc = getf('SliceLocation', 'Unknown');

    % --- 传输语法 ---
    tsuid = getf('TransferSyntaxUID', 'Unknown');

    % --- 切片信息（3D） ---
    sliceLine = '';
    if ~isempty(app.img_Volume) && ndims(app.img_Volume) == 3 && size(app.img_Volume,3) > 1
        sliceLine = sprintf('当前切片：%d / %d', app.currentSliceIdx, size(app.img_Volume,3));
    end

    fmt = [ ...
        '【DICOM 信息】\n' ...
        'PatientName：%s\n' ...
        'PatientID：%s\n' ...
        'Sex/Age：%s / %s\n' ...
        'Modality：%s\n' ...
        'Manufacturer：%s\n' ...
        'Institution：%s\n' ...
        'StudyDesc：%s\n' ...
        'SeriesDesc：%s\n' ...
        'ProtocolName：%s\n' ...
        'StudyDate/Time：%s / %s\n' ...
        'SeriesNumber/InstanceNumber：%s / %s\n' ...
        'Rows x Cols：%s x %s\n' ...
        'PixelSpacing：%s\n' ...
        'SliceThickness：%s\n' ...
        'SpacingBetweenSlices：%s\n' ...
        'Photometric：%s\n' ...
        'SamplesPerPixel：%s\n' ...
        'BitsAllocated/Stored/HighBit：%s / %s / %s\n' ...
        'RescaleSlope/Intercept：%s / %s\n' ...
        'WindowCenter/Width：%s / %s\n' ...
        'BodyPart：%s\n' ...
        'PatientPosition：%s\n' ...
        'ImagePositionPatient：%s\n' ...
        'ImageOrientationPatient：%s\n' ...
        'SliceLocation：%s\n' ...
        'TransferSyntaxUID：%s\n' ...
        '%s\n' ...
    ];

    dicomText = sprintf(fmt, ...
        patientName, patientID, patientSex, patientAge, modality, manufacturer, institution, ...
        studyDesc, seriesDesc, protocol, studyDate, studyTime, seriesNum, instNum, ...
        rows, cols, px, sliceThickness, spacingBetweenSlices, ...
        photo, spp, bitsA, bitsS, highB, ...
        rescaleSlope, rescaleIntercept, windowCenter, windowWidth, ...
        bodyPart, position, ipp, iop, sliceLoc, tsuid, sliceLine);

    % ====== 本地辅助函数：安全转 char ======
    function out = localFieldToChar(s, fn, def)
        if ~isfield(s, fn) || isempty(s.(fn))
            out = char(string(def));
            return;
        end

        v = s.(fn);

        % PatientName 常见 struct
        if isstruct(v)
            if isfield(v,'FamilyName') || isfield(v,'GivenName')
                fam = '';
                giv = '';
                if isfield(v,'FamilyName') && ~isempty(v.FamilyName), fam = char(string(v.FamilyName)); end
                if isfield(v,'GivenName')  && ~isempty(v.GivenName),  giv = char(string(v.GivenName));  end
                out = strtrim([fam ' ' giv]);
                if isempty(out), out = 'Unknown'; end
                return;
            end
            % TransferSyntaxUID 有时也是 struct
            if isfield(v,'UID')
                out = char(string(v.UID));
                return;
            end
            out = 'Unknown';
            return;
        end

        if isnumeric(v)
            out = num2str(v);
            return;
        end

        if ischar(v)
            out = v;
            return;
        end

        if isstring(v)
            out = char(v);
            return;
        end

        if iscell(v)
            try
                out = char(strjoin(string(v), " "));
            catch
                out = 'Unknown';
            end
            return;
        end

        try
            out = char(string(v));
        catch
            out = 'Unknown';
        end
    end
end
function niftiText = formatNiftiInfoText(app)
    niftiText = '';

    if ~app.isNIfTILoaded || isempty(app.niftiInfo)
        return;
    end

    info = app.niftiInfo;

    % 安全取字段（兼容不同版本 info 结构差异）
    getf = @(fn, def) localGetField(info, fn, def);

    fileName = getf('Filename', 'Unknown');
    imgSize  = getf('ImageSize', 'Unknown');
    pixDim   = getf('PixelDimensions', 'Unknown');
    datatype = getf('Datatype', 'Unknown');
    spaceU   = getf('SpaceUnits', 'Unknown');
    timeU    = getf('TimeUnits', 'Unknown');
    desc     = getf('Description', 'Unknown');
    qform    = getf('QformCode', 'Unknown');
    sform    = getf('SformCode', 'Unknown');
    xform    = getf('TransformName', 'Unknown');

    % 格式化数组显示
    imgSizeStr = localVecToStr(imgSize);
    pixDimStr  = localVecToStr(pixDim);

    % 切片显示信息
    sliceLine = '';
    if ~isempty(app.img_Volume) && ndims(app.img_Volume) == 3 && size(app.img_Volume,3) > 1
        sliceLine = sprintf('当前切片：%d / %d', app.currentSliceIdx, size(app.img_Volume,3));
    end

    volLine = '';
    if isfield(app,'niftiVolIdx')
        volLine = sprintf('当前Volume：%d', app.niftiVolIdx);
    end

    fmt = [ ...
        '【NIfTI 信息】\n' ...
        'Filename：%s\n' ...
        'ImageSize：%s\n' ...
        'PixelDimensions：%s\n' ...
        'Datatype：%s\n' ...
        'SpaceUnits/TimeUnits：%s / %s\n' ...
        'Description：%s\n' ...
        'QformCode/SformCode：%s / %s\n' ...
        'TransformName：%s\n' ...
        '%s\n' ...
        '%s\n' ...
    ];

    niftiText = sprintf(fmt, ...
        char(string(fileName)), ...
        imgSizeStr, pixDimStr, ...
        char(string(datatype)), ...
        char(string(spaceU)), char(string(timeU)), ...
        char(string(desc)), ...
        char(string(qform)), char(string(sform)), ...
        char(string(xform)), ...
        volLine, sliceLine);

    % ===== helper =====
    function v = localGetField(s, fn, def)
        if isfield(s, fn) && ~isempty(s.(fn))
            v = s.(fn);
        else
            v = def;
        end
    end

    function out = localVecToStr(v)
        if isnumeric(v)
            out = strtrim(regexprep(num2str(v), '\s+', ' '));
        else
            out = char(string(v));
        end
    end
end
function [V, refInfo] = dicomSeriesToVolume(app, dicomDir) %#ok<INUSL>

    fs = dir(fullfile(dicomDir, '*'));
    fs = fs(~[fs.isdir]);

    % 过滤出 DICOM 文件
    isD = false(numel(fs),1);
    for k = 1:numel(fs)
        try
            isD(k) = isdicom(fullfile(dicomDir, fs(k).name));
        catch
            isD(k) = false;
        end
    end
    fs = fs(isD);
    assert(~isempty(fs), '该目录没有可读的 DICOM 文件：%s', dicomDir);

    % 读取 dicominfo，并尽量用 InstanceNumber 排序
    inst  = nan(numel(fs),1);
    infos = cell(numel(fs),1);
    for i = 1:numel(fs)
        p = fullfile(dicomDir, fs(i).name);
        infos{i} = dicominfo(p);

        if isfield(infos{i}, 'InstanceNumber') && ~isempty(infos{i}.InstanceNumber)
            inst(i) = double(infos{i}.InstanceNumber);
        else
            inst(i) = NaN;
        end
    end

    if all(~isnan(inst))
        [~, order] = sort(inst);
    else
        [~, order] = sort({fs.name});
    end
    fs = fs(order);
    refInfo = infos{order(1)};

    % 堆叠体数据
    first = dicomread(fullfile(dicomDir, fs(1).name));
    [r,c] = size(first);

    V = zeros(r,c,numel(fs), class(first));
    V(:,:,1) = first;

    for i = 2:numel(fs)
        V(:,:,i) = dicomread(fullfile(dicomDir, fs(i).name));
    end
end

function UITableCellSelection(app, src, event)
    if isempty(event) || ~isfield(event,'Indices') || isempty(event.Indices)
        return;
    end
    if isempty(app.roiLabelSeparated)
        return;
    end

    r = event.Indices(1);
    if isempty(app.T) || r > height(app.T)
        return;
    end

    roiID = app.T.ROI_Label(r);

    % ===== 1. 找到该 ROI 出现“最多”的 slice =====
    % 这是关键：而不是用 bestSlice
    volsPerSlice = squeeze(sum(sum(app.roiLabelSeparated == roiID, 1), 2));

    if all(volsPerSlice == 0)
        % 理论上不会发生，兜底
        app.lbl_Status.Text = sprintf('ROI %d 在任何切片中都不存在', roiID);
        return;
    end

    [~, z] = max(volsPerSlice);

    labSlice = app.roiLabelSeparated(:,:,z);
    mask = (labSlice == roiID);

    % ===== 2. 显示 ROI（黑底白标注）=====
    imagesc(app.ax_Processed, mask);
    colormap(app.ax_Processed, gray(256));
    app.ax_Processed.CLim = [0 1];
    axis(app.ax_Processed, 'image');
    app.ax_Processed.Visible = 'off';
    title(app.ax_Processed, sprintf('ROI %d（Slice %d）', roiID, z));

    % ===== 3. 自动放大到 ROI 区域 =====
    [rr, cc] = find(mask);
    if ~isempty(rr)
        pad = 20;  % 放大边距（像素）
        r1 = max(min(rr)-pad, 1);
        r2 = min(max(rr)+pad, size(mask,1));
        c1 = max(min(cc)-pad, 1);
        c2 = min(max(cc)+pad, size(mask,2));

        app.ax_Processed.XLim = [c1 c2];
        app.ax_Processed.YLim = [r1 r2];
    else
        app.ax_Processed.XLimMode = 'auto';
        app.ax_Processed.YLimMode = 'auto';
    end

    % ===== 4. 状态栏 =====
    vol = app.T.Volume_mm3(r);
    app.lbl_Status.Text = sprintf('ROI %d | 体积 %.2f mm^3 | Slice %d', roiID, vol, z);
    drawnow;
end

%% =========================
%  Helpers (MUST be inside methods (Access = private))
% ==========================

function tf = isNiftiPath(app, p) %#ok<INUSD>
    p = lower(string(p));
    tf = endsWith(p, ".nii") || endsWith(p, ".nii.gz");
end

function p2 = prepareNiftiInput(app, p, outDir) %#ok<INUSD>
    % 返回：char 行向量路径（SPM/LST 最稳）
    if isstring(p)
        if ~isscalar(p)
            error("Path must be a string scalar.");
        end
        p = char(p);
    elseif ischar(p)
        % ok
    elseif iscell(p) && numel(p)==1
        p = char(p{1});
    else
        error("Input path type invalid.");
    end

    if isempty(p)
        p2 = '';
        return;
    end

    outDir = char(outDir);

    if endsWith(lower(p), '.nii.gz')
        if ~exist(outDir,'dir'), mkdir(outDir); end
        gunzip(p, outDir);   % 输入必须是 char 标量

        [~, base, ~] = fileparts(p);  % base = 'xxx.nii'
        p2 = fullfile(outDir, base);
    else
        p2 = p;
    end
end


function ensureSPM12(app)
    % 检查 SPM12 是否在 path
    if exist('spm','file')~=2 || exist('spm_jobman','file')~=2
        uialert(app.UIFigure, ...
            "SPM12 was not found on MATLAB path. Please add SPM12 folder to path.", ...
            "SPM12 Not Found");
        error("SPM12 not found on MATLAB path.");
    end
end

function ensureLST(app)
    app.ensureSPM12();

    lstDir = fullfile(spm('Dir'),'toolbox','LST');

    % 1) 必要入口文件存在
    if exist(fullfile(lstDir,'tbx_cfg_LST.m'),'file') ~= 2
        uialert(app.UIFigure, ...
            "LST cfg entry (tbx_cfg_LST.m) not found in: " + string(lstDir), ...
            "LST Not Found");
        error("LST cfg entry not found.");
    end

    % 2) 加载 LST 路径（只加 LST，不要 genpath(spm('Dir'))）
    if ~contains(path, lstDir)
        addpath(genpath(lstDir));
    end
    rehash;

    % 3) 检查你当前 LST 版本实际可调用的函数（ps_LST_*）
    hasLPA = (exist('ps_LST_lpa','file')==2);
    hasLGA = (exist('ps_LST_lga','file')==2);

    if ~(hasLPA || hasLGA)
        uialert(app.UIFigure, ...
            "LST loaded but ps_LST_lpa/ps_LST_lga not found. Check your LST version in: " + string(lstDir), ...
            "LST Function Missing");
        error("ps_LST_lpa/ps_LST_lga not found on path.");
    end

    % 4) 让 SPM 重新加载 batch 配置（包括 LST）
    spm('defaults','fmri');
    spm_jobman('initcfg');
end


% -------- Find output nii helper (as app method) --------
function outPath = findFirstNii(app, folder, keywords) %#ok<INUSD>
    outPath = "";
    fs = dir(fullfile(char(folder), "*.nii*"));
    if isempty(fs), return; end

    names = string({fs.name});
    hit = false(size(names));
    for k = 1:numel(keywords)
        hit = hit | contains(lower(names), lower(keywords(k)));
    end
    if any(hit)
        outPath = string(fullfile(char(folder), names(find(hit,1,'first'))));
        return;
    end

    outPath = string(fullfile(char(folder), fs(1).name));
end

% -------- SPM12 Tissue Segmentation (T1 only) --------
function tissuePaths = runSPM12_TissueSeg(app, t1Path, outDir)

    app.ensureSPM12();
    spm('Defaults','fmri');
    spm_jobman('initcfg');

    outDir = char(outDir);
    if ~exist(outDir,'dir'), mkdir(outDir); end

    % 强制输入为 char（非常关键，避免 char 矩阵/非标量 string）
    t1Path = app.prepareNiftiInput(t1Path, outDir);  % 返回 char 标量路径

    [~, name, ext] = fileparts(t1Path);
    inCopy = fullfile(outDir, [name ext]);
    if ~exist(inCopy,'file')
        copyfile(t1Path, inCopy);
    end

    tpmPath = fullfile(spm('Dir'),'tpm','TPM.nii');
    
    biasreg  = app.biasreg00001001EditField.Value;   % 0.0001~0.01
    biasfwhm = app.biasfwhm30120EditField.Value;     % 30~120

    if isempty(biasreg) || ~isfinite(biasreg), biasreg = 0.001; end
    if isempty(biasfwhm) || ~isfinite(biasfwhm), biasfwhm = 60; end

    matlabbatch = {};
    matlabbatch{1}.spm.spatial.preproc.channel.vols = { [inCopy ',1'] };
    matlabbatch{1}.spm.spatial.preproc.channel.biasreg  = biasreg;
    matlabbatch{1}.spm.spatial.preproc.channel.biasfwhm = biasfwhm;
    matlabbatch{1}.spm.spatial.preproc.channel.write = [0 1];

    % SPM 推荐的 ngaus（更贴近默认）
    ngausDefault = [1 1 2 3 4 2];

    for k = 1:6
        matlabbatch{1}.spm.spatial.preproc.tissue(k).tpm = {sprintf('%s,%d', tpmPath, k)};
        matlabbatch{1}.spm.spatial.preproc.tissue(k).ngaus = ngausDefault(k);
        matlabbatch{1}.spm.spatial.preproc.tissue(k).native = [1 0];  % 输出 c1~c6
        matlabbatch{1}.spm.spatial.preproc.tissue(k).warped = [0 0];
    end

    matlabbatch{1}.spm.spatial.preproc.warp.mrf = 1;
    matlabbatch{1}.spm.spatial.preproc.warp.cleanup = 1;
    matlabbatch{1}.spm.spatial.preproc.warp.reg = [0 0.001 0.5 0.05 0.2];
    matlabbatch{1}.spm.spatial.preproc.warp.affreg = 'mni';
    matlabbatch{1}.spm.spatial.preproc.warp.fwhm = 0;
    matlabbatch{1}.spm.spatial.preproc.warp.samp = 3;
    matlabbatch{1}.spm.spatial.preproc.warp.write = [0 0];

    spm_jobman('run', matlabbatch);

    % 收集输出 c1~c6
    tissuePaths = strings(6,1);
    for k = 1:6
        % SPM 输出通常是 cK + 原文件名
        patt = fullfile(outDir, sprintf('c%d%s*.nii', k, name));
        fs = dir(patt);
        if isempty(fs)
            % 兜底：只要是 cK*.nii 都行
            fs = dir(fullfile(outDir, sprintf('c%d*.nii', k)));
        end
        if isempty(fs)
            error("SPM output not found for c%d in: %s", k, outDir);
        end
        tissuePaths(k) = string(fullfile(outDir, fs(1).name));
    end

    app.setStatus("SPM12 finished: c1~c6 generated.");
end

% -------- LST LPA (FLAIR only) --------
function outMaskPath = runLST_LPA(app, flairPath, outDir)

    app.ensureLST();
    if ~exist(outDir,'dir'), mkdir(outDir); end

    flairPath = app.prepareNiftiInput(flairPath, outDir);

    % 复制输入到输出目录（避免 LST 在原目录写）
    [~,name,ext] = fileparts(char(flairPath));
    inCopy = fullfile(char(outDir), [name ext]);
    if ~exist(inCopy,'file')
        copyfile(char(flairPath), inCopy);
    end

    % ======== 调用 LST (你的版本是 ps_LST_lpa) ========
    try
        % 多数版本：ps_LST_lpa(inCopy)
        ps_LST_lpa(inCopy);
    catch
        try
            % 少数版本：ps_LST_lpa(inCopy, outDir)
            ps_LST_lpa(inCopy, outDir);
        catch ME
            disp(getReport(ME,'extended'));  % 关键：打印出具体哪一行
           
            error("LST LPA (ps_LST_lpa) call failed: %s", ME.message);
             rethrow(ME);
        end
    end

    % 找输出 mask（关键词你可以按实际输出再补）
    outMaskPath = app.findFirstNii(outDir, ["ples","lpa","lesion","mask"]);
    if strlength(outMaskPath)==0
        error("LST LPA finished but lesion mask not found in output folder.");
    end

    app.setStatus("LST LPA finished (ps_LST_lpa).");
end


% -------- LST LGA (T1 + FLAIR) : ONLY RUN --------
function outMaskPath = runLST_LGA(app, t1Path, flairPath)

    app.ensureLST();

    % kappa
   kappa   = app.kappa0108EditField.Value;       % 0.1~0.8
   maxiter = app.maxiter10200EditField.Value;    % 10~200

   if isempty(kappa) || ~isfinite(kappa), kappa = 0.3; end
   if isempty(maxiter) || ~isfinite(maxiter), maxiter = 50; end

    t1Dir = string(fileparts(char(t1Path)));
    t0 = now;

    spm('defaults','fmri');
    spm_jobman('initcfg');

    matlabbatch = {};
    matlabbatch{1}.spm.tools.LST.lga.data_T1 = { [char(t1Path) ',1'] };
    matlabbatch{1}.spm.tools.LST.lga.data_F2 = { [char(flairPath) ',1'] };

    matlabbatch{1}.spm.tools.LST.lga.opts_lga.initial = kappa;
    matlabbatch{1}.spm.tools.LST.lga.opts_lga.mrf     = 1;
    matlabbatch{1}.spm.tools.LST.lga.opts_lga.maxiter = round(maxiter);
    matlabbatch{1}.spm.tools.LST.lga.html_report      = 1;

    spm_jobman('run', matlabbatch);

    % 1) 收集“本次新生成的所有文件”(默认都在 t1Dir)
    newFiles = app.listNewFiles(t1Dir, t0);

    % 2) 如果有保存路径：移动到 saveBaseDir\LGA_时间戳\
    if strlength(app.saveBaseDir) > 0 && exist(char(app.saveBaseDir),'dir')==7
        ts = datestr(now,'yyyymmdd_HHMMSS');
        dstDir = string(fullfile(char(app.saveBaseDir), "LGA_" + ts));
        if ~exist(char(dstDir),'dir'), mkdir(char(dstDir)); end

        newFiles = app.moveFilesToDir(newFiles, dstDir, [string(t1Path); string(flairPath)]);
        app.segOutDir = dstDir;
    else
        app.segOutDir = t1Dir;
    end

    % 3) 保存本次产物路径列表（后续评估/查看都用这个）
    app.lstLgaOutputs = newFiles;

    % 4) 从产物里挑“主 mask”（优先 ples_lga_*.nii）
    outMaskPath = app.pickLgaMask(newFiles);

    % 5) 同步到 app.segMaskPath 供后续评估
    app.segMaskPath = string(outMaskPath);

    app.setStatus("LST-LGA finished. Files=" + string(numel(newFiles)) + ...
                  ", mask=" + string(app.segMaskPath));
end
function files = listNewFiles(app, folder, t0) %#ok<INUSD>
    files = strings(0,1);
    fs = dir(fullfile(char(folder), '*'));
    fs = fs(~[fs.isdir]);
    if isempty(fs), return; end

    fs = fs([fs.datenum] >= (t0 - 3/1440));  % 3分钟缓冲
    if isempty(fs), return; end

    files = strings(numel(fs),1);
    for i = 1:numel(fs)
        files(i) = string(fullfile(fs(i).folder, fs(i).name));
    end
end

function newList = moveFilesToDir(app, files, dstDir, excludePaths) %#ok<INUSD>
    newList = strings(0,1);
    if isempty(files), return; end

    excl = lower(string(excludePaths));

    for i = 1:numel(files)
        src = string(files(i));
        if strlength(src)==0 || exist(char(src),'file')~=2, continue; end

        % 不搬输入文件本体
        if any(lower(src) == excl)
            continue;
        end

        [~, name, ext] = fileparts(char(src));
        dst = string(fullfile(char(dstDir), name + ext));

        % 避免重名覆盖
        if exist(char(dst),'file')==2
            k = 1;
            while exist(fullfile(char(dstDir), sprintf('%s_%d%s', name, k, ext)), 'file')==2
                k = k + 1;
            end
            dst = string(fullfile(char(dstDir), sprintf('%s_%d%s', name, k, ext)));
        end

        try
            movefile(char(src), char(dst));
            newList(end+1,1) = dst; %#ok<AGROW>
        catch
            % 搬不动就保留原路径
            newList(end+1,1) = src; %#ok<AGROW>
        end
    end
end
function maskPath = pickLgaMask(app, files) %#ok<INUSD>
    maskPath = "";

    if isempty(files), return; end
    names = lower(files);

    % 优先：ples_lga_*.nii / nii.gz
    idx = find(contains(names, "ples_lga_") & contains(names, ".nii"), 1, 'first');
    if ~isempty(idx)
        maskPath = files(idx);
        return;
    end

    % 兜底：包含 lga 且是 nii
    idx = find(contains(names, "lga") & contains(names, ".nii"), 1, 'first');
    if ~isempty(idx)
        maskPath = files(idx);
        return;
    end
end

function p = findNewestAfter(app, dirs, pattern, t0) %#ok<INUSD>
    p = "";
    bestTime = -inf;

    for d = dirs.'
        fs = dir(fullfile(char(d), pattern));
        if isempty(fs), continue; end

        % 只保留运行后新生成的文件（给 3 分钟缓冲）
        fs = fs([fs.datenum] >= (t0 - 3/1440));
        if isempty(fs), continue; end

        [~, idx] = max([fs.datenum]);
        if fs(idx).datenum > bestTime
            bestTime = fs(idx).datenum;
            p = string(fullfile(fs(idx).folder, fs(idx).name));
        end
    end
end

function previewMaskOnAxes(app, maskVol, ax, ttl)
    if isempty(maskVol), return; end
    if ndims(maskVol) < 3
        imagesc(ax, maskVol); axis(ax,'image'); ax.Visible='off'; title(ax, ttl);
        return;
    end
    s = squeeze(sum(sum(maskVol>0,1),2));
    [~, z] = max(s);
    img = maskVol(:,:,z);

    cla(ax,'reset');
    imagesc(ax, img);
    colormap(ax, gray(256));
    ax.CLim = [0 1];
    axis(ax, 'image');
    ax.Visible = 'off';
    title(ax, sprintf('%s (Slice %d)', ttl, z));
end

function [dic, iou, precision, recall, f1] = calcSegMetrics(app, pred, gt) %#ok<INUSD>
    pred = logical(pred);
    gt   = logical(gt);

    TP = nnz(pred & gt);
    FP = nnz(pred & ~gt);
    FN = nnz(~pred & gt);

    dic = (2*TP) / (2*TP + FP + FN + eps);
    iou = TP / (TP + FP + FN + eps);

    precision = TP / (TP + FP + eps);
    recall    = TP / (TP + FN + eps);
    f1        = 2 * precision * recall / (precision + recall + eps);
end


function y = ternary(app, cond, a, b) %#ok<INUSD>
    if cond, y = a; else, y = b; end
end
function out = collectLSTLGAOutputs(app, outDir) %#ok<INUSD>
% 收集 LST-LGA 在 outDir 里生成的文件
% 返回 struct，包含 nii/mat/report/png 列表

    out = struct();
    out.outDir = string(outDir);

    f_ples   = dir(fullfile(char(outDir), "ples_lga_*.nii*"));
    f_mat    = dir(fullfile(char(outDir), "LST_lga_*.mat"));
    f_report = dir(fullfile(char(outDir), "report_LST_lga_*.htm*"));
    f_gb      = dir(fullfile(char(outDir), "gb_*.png"));
    f_overlay = dir(fullfile(char(outDir), "overlay_*.png"));

    out.plesNii     = localFilesToStrings(f_ples);
    out.matFile     = localFilesToStrings(f_mat);
    out.report      = localFilesToStrings(f_report);
    out.gbPngs      = localFilesToStrings(f_gb);
    out.overlayPngs = localFilesToStrings(f_overlay);

    if ~isempty(out.plesNii)
        out.mainNii = out.plesNii(1);
    else
        out.mainNii = "";
    end

    function s = localFilesToStrings(f)
        if isempty(f)
            s = strings(0,1);
            return;
        end
        s = strings(numel(f),1);
        for i = 1:numel(f)
            s(i) = string(fullfile(f(i).folder, f(i).name));
        end
    end
end
function s = grade3(app, v, thrGood, thrFair) %#ok<INUSD>
% v: 指标值
% thrGood:  >= thrGood  -> 'Good'
% thrFair:  >= thrFair  -> 'Fair'
% else              -> 'Poor'

    if ~isfinite(v)
        s = 'NA';
        return;
    end

    if v >= thrGood
        s = 'Good';
    elseif v >= thrFair
        s = 'Fair';
    else
        s = 'Poor';
    end
end

function pGT = alignPredToGT(app, pMoving, movingInfo, fixedInfo, interp)
% pGT: moving(pred) 重采样到 fixed(GT) 空间后的结果（double）
% pMoving: 预测体数据（double/logic 都行，但不要是 struct）
% movingInfo: niftiinfo(predPath) 得到的 struct
% fixedInfo : niftiinfo(gtPath)   得到的 struct
% interp: 'nearest' (mask) 或 'linear' (probability)

    if nargin < 5 || isempty(interp)
        interp = 'nearest';
    end

    % --- 0) 防御：确保输入类型正确 ---
    if isstruct(pMoving)
        error("alignPredToGT: pMoving is struct. You passed niftiinfo output by mistake.");
    end
    if ~isstruct(movingInfo) || ~isstruct(fixedInfo)
        error("alignPredToGT: movingInfo/fixedInfo must be niftiinfo structs.");
    end

    % --- 1) 取出 affine（voxel->world），并修正为 MATLAB affine3d 约定 ---
    A_mov = localGetAffine_Matlab(movingInfo);   % voxel->world (MATLAB affine3d 形式)
    A_fix = localGetAffine_Matlab(fixedInfo);

    % --- 2) 构造空间参考（imref3d，用 affine 的世界坐标范围） ---
    R_mov = localMakeRef3d(movingInfo, A_mov);
    R_fix = localMakeRef3d(fixedInfo,  A_fix);

    % --- 3) 构造几何变换 ---
    % 目标：让 moving 的内容落到 fixed 的世界坐标里
    % 体素坐标到世界坐标：
    %   world = A * [i j k 1]'   （这里的 A 已经是 MATLAB affine3d 约定）
    %
    % 推导：对 fixed 输出网格上的一个点 world_fix，
    % 需要找到对应的 moving 输入 world_mov。
    % 由于两者都在同一世界坐标系里，本质上 world_mov = world_fix。
    %
    % imwarp 的 tform（affine3d）在内部做反向映射更稳：
    % 我们构造：world_fix -> world_mov 的变换
    % 但在世界坐标系一致时，它就是 identity。
    % 实际上真正需要的是 voxel_fix -> voxel_mov 的映射关系：
    %   world = A_fix * vox_fix
    %   vox_mov = inv(A_mov) * world
    % 所以：
    %   vox_mov = inv(A_mov) * A_fix * vox_fix
    %
    % 因此给 imwarp 的变换（把 FIXED 网格映射到 MOVING）：
    T = inv(A_mov) * A_fix;

    % affine3d 要求最后一列 [0;0;0;1]，平移在最后一行
    % 我们在 localGetAffine_Matlab 已经保证了，但这里再防御一次：
    T = localEnforceAffine3dConvention(T);

    tform = affine3d(T);   % affine3d 使用转置

    % --- 4) 重采样 ---
    pMoving = double(pMoving);
    pGT = imwarp(pMoving, R_mov, tform, interp, 'OutputView', R_fix);

    % --- 5) Debug ---
    disp("==== ALIGN DEBUG ====");
    disp("moving size: " + mat2str(size(pMoving)) + " -> fixed size: " + mat2str(R_fix.ImageSize));
    disp("R_mov world limits: ");
    disp([R_mov.XWorldLimits; R_mov.YWorldLimits; R_mov.ZWorldLimits]);
    disp("R_fix world limits: ");
    disp([R_fix.XWorldLimits; R_fix.YWorldLimits; R_fix.ZWorldLimits]);
    disp("pGT min/max: " + min(pGT(:)) + " / " + max(pGT(:)));
    disp("pGT nnz(>0): " + nnz(pGT > 0));

    % =========================
    % helpers
    % =========================

    function Aout = localGetAffine_Matlab(info)
        % 从 niftiinfo 提取 Transform，并转换成 MATLAB affine3d 约定：
        %   最后一列必须为 [0;0;0;1]
        %   平移必须在最后一行 (4,1:3)

        Araw = [];

        if isfield(info,'Transform') && ~isempty(info.Transform)
            Tr = info.Transform;

            if isa(Tr,'affine3d')
                Araw = double(Tr.T);
            elseif isstruct(Tr)
                if isfield(Tr,'T') && ~isempty(Tr.T)
                    Araw = double(Tr.T);
                elseif isfield(Tr,'A') && ~isempty(Tr.A)
                    Araw = double(Tr.A);
                end
            end
        end

        % 兜底：没有 Transform 就用像素间距做对角阵
        if isempty(Araw)
            pix = [1 1 1];
            if isfield(info,'PixelDimensions') && numel(info.PixelDimensions) >= 3
                pix = double(info.PixelDimensions(1:3));
            end
            Araw = eye(4);
            Araw(1,1)=pix(1); Araw(2,2)=pix(2); Araw(3,3)=pix(3);
        end

        Araw = double(Araw(1:4,1:4));

        % ---- 关键：把 Araw 转成 affine3d 可接受的形式 ----
        % 情况1：Araw 已经满足 affine3d（最后一列 [0;0;0;1]）
        % 情况2：Araw 是 NIfTI 常见形式（平移在最后一列 Araw(1:3,4)）
        % 我们做一个“智能转换”：
        Aout = localConvertToAffine3dMatrix(Araw);

        % 最后再强制保证约束
        Aout = localEnforceAffine3dConvention(Aout);
    end

    function A2 = localConvertToAffine3dMatrix(A1)
        % 目标：输出 A2 满足 affine3d 约定：
        %   A2(1:3,4)=0, A2(4,4)=1, 平移在 A2(4,1:3)

        A2 = A1;

        % 先判断：最后一列是否已经是 [0;0;0;1]
        col4 = A1(:,4);
        isAff3dColOK = (abs(col4(1))<1e-9) && (abs(col4(2))<1e-9) && (abs(col4(3))<1e-9) && (abs(col4(4)-1)<1e-9);

        if isAff3dColOK
            % 已经是 affine3d 约定（至少列4 OK），直接返回
            return;
        end

        % 否则：尝试把 NIfTI 的平移（在 A1(1:3,4)）搬到 affine3d 的最后一行
        t = A1(1:3,4);

        % 清零最后一列前三项，并设置 4,4=1
        A2(1:3,4) = 0;
        A2(4,4)   = 1;

        % 把平移写到最后一行前3项（行向量）
        A2(4,1:3) = t(:)';

        % 旋转/缩放部分保留 A1(1:3,1:3)
        A2(1:3,1:3) = A1(1:3,1:3);
    end

    function A = localEnforceAffine3dConvention(A)
        A = double(A(1:4,1:4));
        % affine3d 强制约束：最后一列 [0;0;0;1]
        A(1:3,4) = 0;
        A(4,4)   = 1;
        % 平移只能在最后一行 (4,1:3)，这里不动它
    end

    function R = localMakeRef3d(info, A)
        % 用 affine 的 8 个角点算 world limits，比只用 PixelDimensions 更稳
        sz = double(info.ImageSize);
        if numel(sz) < 3, sz(3)=1; end

        % MATLAB 的体数据是 [rows cols slices] = [Y X Z]
        % world 中的 voxel 坐标我们用 [x y z] = [col row slice]
        corners = [
            0        0        0        1
            sz(2)-1  0        0        1
            0        sz(1)-1  0        1
            0        0        sz(3)-1  1
            sz(2)-1  sz(1)-1  0        1
            sz(2)-1  0        sz(3)-1  1
            0        sz(1)-1  sz(3)-1  1
            sz(2)-1  sz(1)-1  sz(3)-1  1
        ]';

        % A 是 affine3d 约定，但它依然是一个 4x4 的仿射矩阵
        W = A * corners;

        xw = W(1,:); yw = W(2,:); zw = W(3,:);

        xlim = [min(xw) max(xw)];
        ylim = [min(yw) max(yw)];
        zlim = [min(zw) max(zw)];

        if abs(diff(xlim)) < eps, xlim = xlim + [-0.5 0.5]; end
        if abs(diff(ylim)) < eps, ylim = ylim + [-0.5 0.5]; end
        if abs(diff(zlim)) < eps, zlim = zlim + [-0.5 0.5]; end

        R = imref3d([sz(1) sz(2) sz(3)], xlim, ylim, zlim);
    end

end

    end
   

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UITable2.ColumnName = {'Metric','Value','Status'};
    app.UITable2.RowName = {};

   app.UITable2.Data = {
    'DSC',      '',      '';
    'Precision', '', '';
    'Recall',    '',    '';
    'F1',        '',        '';
    'Jaccard',   '',       '';
};

        end

        % Button pushed function: btn_ImportDICOM
        function btn_ImportDICOMPushed(app, event)
            [f, p] = uigetfile({'*.dcm;*.ima', 'DICOM Files'}, 'Select DICOM');
            if isequal(f, 0), return; end

            try
                % 进度条
                d = uiprogressdlg(app.UIFigure, 'Title', '正在读取 DICOM 序列...', 'Indeterminate', 'on');

                % --- 核心修改：尝试读取整个文件夹的序列 ---
                % 1. 获取该文件夹下所有 dcm 文件
                fileList = dir(fullfile(p, '*.dcm'));
                if isempty(fileList)
                    fileList = dir(fullfile(p, '*.ima')); % 兼容 .ima 格式
                end

                numFiles = length(fileList);

                % 2. 智能判断：是单文件 Mosaic 还是多文件序列？
                % 如果文件夹里只有一个文件，或者数据本身就是 4D 的
                rawInfo = dicominfo(fullfile(p, f));
                firstImg = dicomread(fullfile(p, f));

                app.img_Volume = [];

                if numFiles > 1 && ndims(firstImg) == 2
                    % === 情况 A：文件夹里有很多切片 (常规 MRI/CT) ===
                    % 按文件名排序确保顺序正确
                    [~, reindex] = sort({fileList.name});
                    fileList = fileList(reindex);

                    % 预分配内存
                    app.img_Volume = zeros([size(firstImg), numFiles]);

                    % 循环读取每一张切片
                    for i = 1:numFiles
                        app.img_Volume(:,:,i) = dicomread(fullfile(p, fileList(i).name));
                    end
                    is3D = true;
                    statusMsg = sprintf('状态：已导入序列 (%d 张切片)', numFiles);

                else
                    % === 情况 B：单文件 (可能是 DTI Mosaic 或 3D 原始数据) ===
                    rawImg = squeeze(firstImg);
                    if ndims(rawImg) == 3
                        app.img_Volume = rawImg;
                        is3D = true;
                        statusMsg = sprintf('状态：已导入 3D 单文件 (%d 层)', size(rawImg,3));
                    elseif ndims(rawImg) == 4
                        app.img_Volume = squeeze(rawImg(:,:,1,:)); % 尝试降维
                        is3D = true;
                        statusMsg = '状态：已导入 4D 数据';
                    else
                        % 这就是你现在的 DTI Mosaic 情况，它就是一张 2D 图
                        app.img_Volume = rawImg;
                        is3D = false;
                        statusMsg = '状态：已导入 2D 图像 (可能是 DTI Mosaic)';
                    end
                end
                app.dicomInfo = rawInfo;
                app.isDICOMLoaded = true;
                app.isNIfTILoaded = false;
                app.niftiInfo = [];

                % 3. 归一化处理 (uint16 -> double 0~1)
                app.img_Volume = double(app.img_Volume);
                minVal = min(app.img_Volume(:));
                maxVal = max(app.img_Volume(:));
                if maxVal > minVal
                    app.img_Volume = (app.img_Volume - minVal) / (maxVal - minVal);
                end

                % 4. 设置显示逻辑
                if is3D
                    % 是 3D 数据：显示中间层，启用滑块
                    app.currentSliceIdx = round(size(app.img_Volume, 3) / 2);

                    app.slider_Slice.Limits = [1, size(app.img_Volume, 3)];
                    app.slider_Slice.Value = app.currentSliceIdx;
                    app.slider_Slice.Visible = 'on';
                    app.lbl_SliceInfo.Visible = 'on';
                    app.lbl_SliceInfo.Text = sprintf('Slice: %d/%d', app.currentSliceIdx, size(app.img_Volume, 3));
                else
                    % 是 2D 数据：直接显示，隐藏滑块
                    app.currentSliceIdx = 1;
                    app.slider_Slice.Visible = 'off';
                    app.lbl_SliceInfo.Visible = 'off';
                end

                % 5. 提取当前层并显示
                if is3D
                    app.img_Original = app.img_Volume(:, :, app.currentSliceIdx);
                else
                    app.img_Original = app.img_Volume;
                end

                app.img_Gray = app.img_Original;
                app.img_Processed = app.img_Original;
                app.isColorImage = false;

                imshow(app.img_Original, 'Parent', app.ax_Original);
                title(app.ax_Original, '原始图像 (Original)');
                
                % 更新状态栏
                patID = 'Unknown';
                if isfield(rawInfo, 'PatientID'), patID = rawInfo.PatientID; end
                app.lbl_Status.Text = [statusMsg ' | ID: ' patID];

                updateDisplay(app);
                close(d);

            catch ME
                if exist('d','var'), close(d); end
                uialert(app.UIFigure, ME.message, '导入失败');
            end


        end

        % Button pushed function: btn_GrayConvert
        function btn_GrayConvertPushed(app, event)
            if isempty(app.img_Original), return; end

            if size(app.img_Original, 3) == 3
                app.img_Gray = rgb2gray(app.img_Original);
                app.img_Processed = app.img_Gray;
                app.isColorImage = false;

                updateDisplay(app);
                app.lbl_Status.Text = '状态：已转换为灰度图';
            else
                app.lbl_Status.Text = '状态：已经是灰度图，无需转换';
            end
        end

        % Value changed function: slider_Brightness
        function slider_BrightnessValueChanged(app, event)
            app.applyBrightnessContrast();
        end

        % Value changed function: dd_EdgeAlgorithm
        function dd_EdgeAlgorithmValueChanged(app, event)
            if isempty(app.img_Processed), return; end
            
            algo = app.dd_EdgeAlgorithm.Value;
            if strcmp(algo, 'None')
                % 如果选 None，恢复到 img_Gray
                app.img_Processed = app.img_Gray;
                app.applyBrightnessContrast(); % 重新应用当前的亮度对比度
                return;
            end
            
            % 边缘检测前先高斯滤波去噪 (解决假阳性问题)
            if size(app.img_Processed, 3) == 3
                imgForEdge = rgb2gray(app.img_Processed);
            else
                imgForEdge = app.img_Processed;
            end
            imgFiltered = imgaussfilt(imgForEdge, 1.0); 
            
            % 检测
            edges = edge(imgFiltered, lower(algo));
            
            % 边缘图是逻辑值，转为 double 显示
            app.img_Processed = double(edges);
            
            updateDisplay(app);
            app.lbl_Status.Text = ['状态：应用边缘检测 - ' algo];
        end

        % Button pushed function: btn_Reset
        function btn_ResetPushed(app, event)
            if isempty(app.img_Original), return; end
            
            app.img_Processed = app.img_Original;
            % 恢复灰度基准
            if size(app.img_Original, 3) == 3
                app.img_Gray = rgb2gray(app.img_Original);
                app.isColorImage = true;
            else
                app.img_Gray = app.img_Original;
                app.isColorImage = false;
            end
            
            app.slider_Brightness.Value = 0;
            app.slider_Contrast.Value = 1.0;
            app.dd_EdgeAlgorithm.Value = 'None';
            
            imshow(app.img_Original, 'Parent', app.ax_Original);
            axis(app.ax_Original, 'image');
            updateDisplay(app);
            app.lbl_Status.Text = '状态：已还原至初始状态';
        end

        % Button pushed function: btn_ImportStandard
        function btn_ImportStandardButtonPushed(app, event)
            [f, p] = uigetfile({'*.jpg;*.png;*.bmp;*.tif', 'Image Files'}, 'Select Image');
            if isequal(f, 0), return; end
           app.isDICOMLoaded = false;
           app.dicomInfo = struct();
           app.isNIfTILoaded = false;
           app.niftiInfo = [];

            try
                rawImg = imread(fullfile(p, f));
                % 去除透明通道
                if size(rawImg,3) == 4, rawImg = rawImg(:,:,1:3); end

                % 转换为 double 0-1
                app.img_Original = im2double(rawImg);
                app.img_Processed = app.img_Original;

                % 判断是否为彩色
                if size(rawImg, 3) == 3
                    app.isColorImage = true;
                    app.img_Gray = rgb2gray(app.img_Original); % 备一份灰度版用于处理
                else
                    app.isColorImage = false;
                    app.img_Gray = app.img_Original;
                end

                imshow(app.img_Original, 'Parent', app.ax_Original);
                axis(app.ax_Original, 'image');
                title(app.ax_Original, '原始图像');

                app.lbl_Status.Text = sprintf('状态：已导入图像 | %s', f);

                app.slider_Brightness.Value = 0;
                app.slider_Contrast.Value = 1.0;
                updateDisplay(app);

            catch ME
                uialert(app.UIFigure, '文件无法读取或格式不支持', '错误'); 
            end
        end

        % Button pushed function: btn_HistEq
        function btn_HistEqButtonPushed(app, event)
            if isempty(app.img_Processed), return; end
            
            % 必须在灰度图上操作
            if size(app.img_Processed, 3) == 3
                imgIn = rgb2gray(app.img_Processed);
            else
                imgIn = app.img_Processed;
            end
            
            app.img_Processed = histeq(imgIn);
            % 同步更新 img_Gray 以便滑块在此基础上微调(可选)
            app.img_Gray = app.img_Processed; 
            
            updateDisplay(app);
            app.lbl_Status.Text = '状态：已应用全局直方图均衡化';            
        end

        % Button pushed function: btn_CLAHE
        function btn_CLAHEButtonPushed(app, event)
            if isempty(app.img_Processed), return; end
             
            if size(app.img_Processed, 3) == 3
                imgIn = rgb2gray(app.img_Processed);
            else
                imgIn = app.img_Processed;
            end
            
            % CLAHE 算法
            app.img_Processed = adapthisteq(imgIn, 'ClipLimit', 0.02, 'Distribution', 'rayleigh');
            app.img_Gray = app.img_Processed;
            
            updateDisplay(app);
            app.lbl_Status.Text = '状态：已应用 CLAHE (自适应均衡化)';            
        end

        % Value changed function: slider_Contrast
        function slider_ContrastValueChanged(app, event)
            app.applyBrightnessContrast();         
        end

        % Button pushed function: btn_Save
        function btn_SaveButtonPushed(app, event)
            if isempty(app.img_Processed), return; end
            
            [f, p] = uiputfile({'*.png';'*.jpg'}, 'Save Result', 'result.png');
            if isequal(f, 0), return; end
            
            imwrite(app.img_Processed, fullfile(p, f));
            app.lbl_Status.Text = ['状态：已保存文件至 ' f];
            uialert(app.UIFigure, '保存成功！', 'Success');            
        end

        % Callback function
        function btn_Zoom1xButtonPushed(app, event)
            zoom(app.ax_Processed, 'out'); zoom(app.ax_Processed, 'off');
            app.ax_Processed.XLimMode = 'auto'; app.ax_Processed.YLimMode = 'auto';            
        end

        % Callback function
        function btn_Zoom2xButtonPushed(app, event)
            zoom(app.ax_Processed, 2);            
        end

        % Callback function
        function btn_Zoom4xButtonPushed(app, event)
            zoom(app.ax_Processed, 4);            
        end

        % Value changed function: slider_Slice
        function slider_SliceValueChanged(app, event)
            if isempty(app.img_Volume), return; end

            idx = round(app.slider_Slice.Value);
            app.currentSliceIdx = idx;
            app.lbl_SliceInfo.Text = sprintf('Slice: %d/%d', idx, size(app.img_Volume, 3));

            % 更新当前原始图像
            app.img_Original = app.img_Volume(:, :, idx);
            % 重新初始化处理链
            app.img_Gray = app.img_Original;

            % 更新显示原始图
            imshow(app.img_Original, 'Parent', app.ax_Original);

            % 重新应用当前的亮度/对比度设置到新切片
            applyBrightnessContrast(app);

            app.lbl_Status.Text = sprintf('状态：切换至切片层 %d', idx);
        end

        % Callback function
        function btn_GaussButtonPushed(app, event)
            if isempty(app.img_Processed), return; end

            % 对当前处理后的图像进行滤波
            % Sigma 设为 1.0 (根据你的实验报告要求)
            app.img_Processed = imgaussfilt(app.img_Processed, 1.0);

            % 更新 img_Gray 使得后续调节基于滤波后的结果 (可选，视逻辑而定)
            % 这里我们仅作为后处理步骤

            updateDisplay(app);
            app.lbl_Status.Text = '状态：已应用高斯滤波 (Sigma=1.0) 去除噪声';
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
              fig = uifigure( ...
        'Name','作者信息', ...
        'Position',[500 400 320 180], ...
        'WindowStyle','modal');

    uilabel(fig, ...
        'Text', {'班级：生医工一班', ...
                 '学号：20233008204', ...
                 '名字：郭力玮'}, ...
        'Position',[40 70 260 100], ...
        'FontSize',24, ...
        'HorizontalAlignment','left');

    uibutton(fig, 'push', ...
        'Text','确定', ...
        'Position',[120 15 80 30], ...
        'ButtonPushedFcn', @(btn,event) delete(fig));
        end

        % Button pushed function: DICOMButton
        function DICOMButtonPushed(app, event)
                 if isempty(app.img_Original)
        uialert(app.UIFigure, '请先导入一张常规图像（jpg/png）再转换为 DICOM。', '提示');
        app.lbl_Status.Text = '状态：未检测到可转换的自然图像';
        return;
    end

    % 取当前原图（保持彩色/灰度）
    I = app.img_Original;

    % dicomwrite 推荐 uint8/uint16，这里统一 uint8
    Iu8 = im2uint8(I);
    [H, W, C] = size(Iu8);

    % 构造 DICOM 元信息
    info = struct;
    info.PatientName       = 'Test^NaturalImage';
    info.PatientID         = 'NATURAL001';
    info.StudyDescription  = 'Demo Natural Image Study';
    info.SeriesDescription = 'NaturalImageToDICOM';
    info.Rows              = H;
    info.Columns           = W;
    info.Modality          = 'OT';

    if C == 3
        info.SamplesPerPixel           = 3;
        info.PhotometricInterpretation = 'RGB';
        info.BitsAllocated             = 8;
        info.BitsStored                = 8;
        info.HighBit                   = 7;
        info.PlanarConfiguration       = 0;
        defaultName = 'natural_image_color.dcm';
    else
        info.SamplesPerPixel           = 1;
        info.PhotometricInterpretation = 'MONOCHROME2';
        info.BitsAllocated             = 8;
        info.BitsStored                = 8;
        info.HighBit                   = 7;
        defaultName = 'natural_image_gray.dcm';
    end

    % 选择保存路径
    [saveName, savePath] = uiputfile('*.dcm', '保存为 DICOM 文件', defaultName);
    if isequal(saveName, 0)
        app.lbl_Status.Text = '状态：已取消保存 DICOM';
        return;
    end
    outFull = fullfile(savePath, saveName);

    % 写出
    try
        dicomwrite(Iu8, outFull, info);
    catch ME
        uialert(app.UIFigure, ME.message, '写入 DICOM 失败');
        app.lbl_Status.Text = '状态：写入 DICOM 失败';
        return;
    end

    % 读回验证：读图 + 读 dicominfo（让 txt_Stats 也显示）
    try
        J = dicomread(outFull);
        Jinfo = dicominfo(outFull);
    catch ME
        uialert(app.UIFigure, ME.message, '读回 DICOM 失败');
        app.lbl_Status.Text = '状态：读回 DICOM 失败';
        return;
    end

    % 标记为 DICOM
    app.isDICOMLoaded = true;
    app.dicomInfo = Jinfo;

    % 显示：左原图，右读回图
    imshow(I, 'Parent', app.ax_Original);
    axis(app.ax_Original, 'image');
    title(app.ax_Original, '自然图像（当前原图）');

    imshow(J, 'Parent', app.ax_Processed);
    axis(app.ax_Processed, 'image');
    title(app.ax_Processed, 'DICOM 读回验证');

    % 同步处理链数据（后续统计/直方图/处理继续可用）
    app.img_Processed = im2double(J);
    if size(app.img_Processed, 3) == 3
        app.isColorImage = true;
        app.img_Gray = rgb2gray(app.img_Processed);
    else
        app.isColorImage = false;
        app.img_Gray = app.img_Processed;
    end

    updateDisplay(app);
    app.lbl_Status.Text = ['状态：已将当前自然图像保存为 DICOM | ' saveName];
        end

        % Button pushed function: NIfTIButton
        function NIfTIButtonPushed(app, event)
            [f, p] = uigetfile({'*.nii;*.nii.gz', 'NIfTI Files (*.nii, *.nii.gz)'}, 'Select NIfTI');
    if isequal(f, 0)
        app.lbl_Status.Text = '状态：已取消导入 NIfTI';
        return;
    end

    fullpath = fullfile(p, f);

    try
        app.lbl_Status.Text = '状态：正在读取 NIfTI...';

        info = niftiinfo(fullpath);
        V = niftiread(info);   % 直接用 info 更稳

        % 标记：当前为 NIfTI（并清空 DICOM 标记避免混淆）
        app.isNIfTILoaded = true;
        app.niftiInfo = info;
        app.niftiVolIdx = 1;

        app.isDICOMLoaded = false;
        app.dicomInfo = struct();

        % 统一转 double（保留原强度范围先不归一化，后面做 min-max）
        V = double(V);

        % 处理维度：支持 2D/3D/4D
        sz = size(V);

        if ndims(V) == 2
            vol3 = V;
            is3D = false;
        elseif ndims(V) == 3
            vol3 = V;
            is3D = true;
        else
            % 4D：默认取第 1 个 volume：V(:,:, :, 1)
            vol3 = V(:,:,:,app.niftiVolIdx);
            is3D = true;
        end

        % min-max 归一化到 0~1（与你现有处理链一致）
        minVal = min(vol3(:));
        maxVal = max(vol3(:));
        if maxVal > minVal
            vol3 = (vol3 - minVal) / (maxVal - minVal);
        else
            vol3 = zeros(size(vol3));
        end

        % 写入 app.img_Volume / 显示逻辑复用你 DICOM 方式
        if is3D
            app.img_Volume = vol3;
            app.currentSliceIdx = round(size(app.img_Volume, 3) / 2);

            app.slider_Slice.Limits = [1, size(app.img_Volume, 3)];
            app.slider_Slice.Value = app.currentSliceIdx;
            app.slider_Slice.Visible = 'on';
            app.lbl_SliceInfo.Visible = 'on';
            app.lbl_SliceInfo.Text = sprintf('Slice: %d/%d', app.currentSliceIdx, size(app.img_Volume, 3));

            app.img_Original = app.img_Volume(:,:,app.currentSliceIdx);
        else
            app.img_Volume = [];
            app.currentSliceIdx = 1;
            app.slider_Slice.Visible = 'off';
            app.lbl_SliceInfo.Visible = 'off';

            app.img_Original = vol3;
        end

        app.img_Gray = app.img_Original;
        app.img_Processed = app.img_Original;
        app.isColorImage = false;

        imshow(app.img_Original, 'Parent', app.ax_Original);
        title(app.ax_Original, '原始图像 (NIfTI)');

        updateDisplay(app);

        app.lbl_Status.Text = ['状态：已导入 NIfTI | ' f];

    catch ME
        uialert(app.UIFigure, ME.message, '导入 NIfTI 失败');
        app.lbl_Status.Text = '状态：导入 NIfTI 失败';
    end
        end

        % Button pushed function: DICOMNIfTIButton
        function DICOMNIfTIButtonPushed(app, event)
            % Button pushed function: btn_DICOM2NIfTI


    % === 1. 选择 DICOM 序列文件夹 ===
    dicomDir = uigetdir(pwd, '选择一个 DICOM 序列文件夹（DICOM → NIfTI）');
    if isequal(dicomDir, 0)
        app.lbl_Status.Text = '状态：已取消 DICOM→NIfTI';
        return;
    end

    app.lbl_Status.Text = '状态：正在读取 DICOM 序列...';

    % === 2. 读取并堆叠 DICOM 序列 ===
    try
       [V, refInfo] = app.dicomSeriesToVolume(dicomDir);
    catch ME
        uialert(app.UIFigure, ME.message, '读取 DICOM 序列失败');
        return;
    end

    % === 3. 保存为 NIfTI ===
    [saveName, savePath] = uiputfile({'*.nii','NIfTI (*.nii)'}, ...
        '保存为 NIfTI 文件', 'from_dicom.nii');
    if isequal(saveName, 0)
        app.lbl_Status.Text = '状态：已取消保存 NIfTI';
        return;
    end
    outNii = fullfile(savePath, saveName);

    try
        niftiwrite(V, outNii, 'Compressed', false);
    catch ME
        uialert(app.UIFigure, ME.message, '写入 NIfTI 失败');
        return;
    end

    % === 4. 转换后：立刻加载 NIfTI 到 App ===
    try
        info = niftiinfo(outNii);
        Vn  = double(niftiread(info));
    catch ME
        uialert(app.UIFigure, ME.message, '读回 NIfTI 失败');
        return;
    end

    % 归一化到 0–1（与现有处理链一致）
    minV = min(Vn(:)); maxV = max(Vn(:));
    if maxV > minV
        Vn = (Vn - minV) / (maxV - minV);
    else
        Vn = zeros(size(Vn));
    end

    % === 5. 同步到 App 状态 ===
    app.img_Volume      = Vn;
    app.currentSliceIdx = round(size(Vn,3)/2);

    app.slider_Slice.Limits  = [1 size(Vn,3)];
    app.slider_Slice.Value   = app.currentSliceIdx;
    app.slider_Slice.Visible = 'on';
    app.lbl_SliceInfo.Visible = 'on';
    app.lbl_SliceInfo.Text = sprintf('Slice: %d/%d', ...
        app.currentSliceIdx, size(Vn,3));

    app.img_Original  = app.img_Volume(:,:,app.currentSliceIdx);
    app.img_Gray      = app.img_Original;
    app.img_Processed = app.img_Original;
    app.isColorImage  = false;

    % 标记为 NIfTI
    app.isNIfTILoaded = true;
    app.niftiInfo    = info;

    app.isDICOMLoaded = false;
    app.dicomInfo = struct();

    imshow(app.img_Original, 'Parent', app.ax_Original);
    title(app.ax_Original, '原始图像（NIfTI）');

    updateDisplay(app);
    app.lbl_Status.Text = ['状态：DICOM→NIfTI 完成并已加载 | ' saveName];


        end

        % Button pushed function: NIfTIDICOMButton
        function NIfTIDICOMButtonPushed(app, event)
% Button pushed function: btn_NIfTI2DICOM

if isempty(app.img_Volume)
    uialert(app.UIFigure, '请先导入 NIfTI（确保有体数据 img_Volume）。', '提示');
    return;
end
if ndims(app.img_Volume) ~= 3
    uialert(app.UIFigure, '当前体数据不是 3D（H×W×Slices）。', '提示');
    return;
end

outDir = uigetdir(pwd, '选择输出 DICOM 序列文件夹（NIfTI->DICOM）');
if isequal(outDir, 0)
    app.lbl_Status.Text = '状态：已取消输出 DICOM';
    return;
end

% 体数据 -> int16
X = double(app.img_Volume);
X = X - min(X(:));
if max(X(:)) > 0
    X = X ./ max(X(:));
end
V16 = int16(round(X * double(intmax('int16'))));

[r,c,nz] = size(V16);

% ===== 构造 DICOM 元信息 =====
meta = struct();
meta.PatientName         = 'NIfTI^Converted';
meta.PatientID           = 'NIFTI2DICOM';
meta.Modality            = 'MR';

% 必需：SOPClassUID (0008,0016)
% MR Image Storage = 1.2.840.10008.5.1.4.1.1.4
meta.SOPClassUID         = '1.2.840.10008.5.1.4.1.1.4';

% UID（同一套序列固定）
meta.StudyInstanceUID    = dicomuid;
meta.SeriesInstanceUID   = dicomuid;

meta.SeriesDescription   = 'Converted from NIfTI';
meta.Manufacturer        = 'MATLAB';

meta.Rows                = r;
meta.Columns             = c;

meta.SamplesPerPixel           = 1;
meta.PhotometricInterpretation = 'MONOCHROME2';

meta.BitsAllocated       = 16;
meta.BitsStored          = 16;
meta.HighBit             = 15;
meta.PixelRepresentation = 1;    % 1 = signed

% 常用日期时间字段（建议补齐）
meta.StudyDate  = datestr(now,'yyyymmdd');
meta.StudyTime  = datestr(now,'HHMMSS');
meta.SeriesDate = meta.StudyDate;
meta.SeriesTime = meta.StudyTime;
meta.SeriesNumber = 1;
meta.InstanceCreationDate = meta.StudyDate;
meta.InstanceCreationTime = meta.StudyTime;

% 像素间距 / 层厚（尽量从 niftiInfo 读）
dz = 1;
if app.isNIfTILoaded && ~isempty(app.niftiInfo) && isfield(app.niftiInfo,'PixelDimensions')
    pd = app.niftiInfo.PixelDimensions;
    if isnumeric(pd)
        if numel(pd) >= 2
            meta.PixelSpacing = [pd(1) pd(2)];
        end
        if numel(pd) >= 3
            meta.SliceThickness = pd(3);
            dz = pd(3);
        end
    end
end

try
    for z = 1:nz
        meta.InstanceNumber = z;

        % 必需：每张切片唯一 SOPInstanceUID (0008,0018)
        meta.SOPInstanceUID = dicomuid;

        % 简化的方向/位置（轴向）
        meta.ImageOrientationPatient = [1 0 0 0 1 0];
        meta.ImagePositionPatient    = [0 0 (z-1)*dz];

        outName = fullfile(outDir, sprintf('IM_%04d.dcm', z));

        % 这里用 Create（不要用 Copy）
        dicomwrite(V16(:,:,z), outName, meta, 'CreateMode', 'Create');
    end
catch ME
    uialert(app.UIFigure, ME.message, 'NIfTI->DICOM 失败');
    return;
end

% ===== 转换后：同步“DICOM 信息”到 txt_Stats（显示数据不变）=====
try
    firstDicom = fullfile(outDir, 'IM_0001.dcm');
    if exist(firstDicom, 'file')
        app.dicomInfo = dicominfo(firstDicom);
        app.isDICOMLoaded = true;
    else
        app.dicomInfo = meta;   % 兜底：用我们构造的 meta
        app.isDICOMLoaded = true;
    end
catch
    app.dicomInfo = meta;
    app.isDICOMLoaded = true;
end

app.isNIfTILoaded = false;
app.niftiInfo = [];

% 显示仍按当前体数据（不需要重读 DICOM）
app.currentSliceIdx = round(size(app.img_Volume, 3) / 2);
app.slider_Slice.Limits = [1, size(app.img_Volume, 3)];
app.slider_Slice.Value  = app.currentSliceIdx;
app.slider_Slice.Visible = 'on';
app.lbl_SliceInfo.Visible = 'on';
app.lbl_SliceInfo.Text = sprintf('Slice: %d/%d', app.currentSliceIdx, size(app.img_Volume, 3));

app.img_Original  = app.img_Volume(:,:,app.currentSliceIdx);
app.img_Gray      = app.img_Original;
app.img_Processed = app.img_Original;
app.isColorImage  = false;

imshow(app.img_Original, 'Parent', app.ax_Original);
title(app.ax_Original, '原始图像 (DICOM from NIfTI)');

updateDisplay(app);
app.lbl_Status.Text = sprintf('状态：NIfTI->DICOM 完成并已同步信息 | 输出 %d 张', nz);


        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            app.isHistShown = ~app.isHistShown;   % 点击一次切换显示/隐藏
    updateDisplay(app);
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            value = app.Slider.Value;
        
    zoomFactor = app.Slider.Value;

    ax = app.ax_Processed;

    % 没有图像时直接返回
    if isempty(ax.Children)
        return;
    end

    % 关闭 MATLAB 内建 zoom，避免冲突
    zoom(ax, 'off');

    % 强制获取当前轴范围
    drawnow;
    xl = ax.XLim;
    yl = ax.YLim;

    % 当前中心
    cx = mean(xl);
    cy = mean(yl);

    % 根据倍率计算新显示范围
    newW = diff(xl) / zoomFactor;
    newH = diff(yl) / zoomFactor;

    ax.XLim = [cx - newW/2, cx + newW/2];
    ax.YLim = [cy - newH/2, cy + newH/2];


        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
                % ====== 1) 选择 NIfTI 分割文件 ======
    [fileName, filePath] = uigetfile( ...
        {'*.nii;*.nii.gz', 'NIfTI files (*.nii, *.nii.gz)'}, ...
        '选择图像文件');

    if isequal(fileName,0)
        app.lbl_Status.Text = '状态：已取消选择文件';
        drawnow;
        return;
    end

    app.filePath = fullfile(filePath, fileName);

    % ====== 2) 读取数据 ======
    app.lbl_Status.Text = '状态：正在读取 NIfTI 分割数据...';
    drawnow;

    try
        labelVol = niftiread(app.filePath);
        info     = niftiinfo(app.filePath);

        app.lbl_Status.Text = '状态：读取成功，计算体素体积...';
        drawnow;

        voxelSize   = double(info.PixelDimensions(1:3)); % [dx dy dz]
        voxelVolume = prod(voxelSize);                   % mm^3

        % ====== 3) 自动选取“非零最多”的切片用于预览 ======
        app.lbl_Status.Text = '状态：正在自动选择最佳预览切片...';
        drawnow;

        if ndims(labelVol) < 3 || size(labelVol,3) == 1
            bestSlice = 1;
        else
            sliceSum = squeeze(sum(sum(labelVol > 0, 1), 2));
            [maxVal, bestSlice] = max(sliceSum);
            if isempty(bestSlice) || maxVal == 0
                bestSlice = round(size(labelVol, 3) / 2);
            end
        end

        % ====== 4) 显示原始分割（黑底白标注）到 ax_Original ======
        app.lbl_Status.Text = sprintf('状态：显示第 %d 层预览（黑底白标注）...', bestSlice);
        drawnow;

        maskSlice = labelVol(:,:,bestSlice) > 0;
        imagesc(app.ax_Original, maskSlice);
        colormap(app.ax_Original, gray(256));
        app.ax_Original.CLim = [0 1];
        axis(app.ax_Original, 'image');
        app.ax_Original.Visible = 'off';
        title(app.ax_Original, sprintf('分割预览 (Slice %d)', bestSlice));

        % ====== 5) 3D 连通域分离 ROI ======
        app.lbl_Status.Text = '状态：正在进行 3D 连通域分离 ROI...';
        drawnow;

        binaryWMH = labelVol > 0;
        CC = bwconncomp(binaryWMH, 26);

        labelSeparated = zeros(size(labelVol), 'uint32');
        for i = 1:CC.NumObjects
            labelSeparated(CC.PixelIdxList{i}) = i;
        end

        % ====== 6) 显示分离后的 ROI（黑底白标注）到 ax_Processed ======
        maskSep = labelSeparated(:,:,bestSlice) > 0;
        imagesc(app.ax_Processed, maskSep);
        colormap(app.ax_Processed, gray(256));
        app.ax_Processed.CLim = [0 1];
        axis(app.ax_Processed, 'image');
        app.ax_Processed.Visible = 'off';
        title(app.ax_Processed, sprintf('ROI 分离结果 (Slice %d)', bestSlice));

        % ====== 7) 统计每个 ROI：体积 + 质心坐标（mm） ======
        app.lbl_Status.Text = '状态：正在统计体积与质心坐标...';
        drawnow;

        roiLabels = (1:CC.NumObjects).';
        voxelCounts = cellfun(@numel, CC.PixelIdxList).';
        roiVolume   = voxelCounts * voxelVolume;   % mm^3

        stats   = regionprops3(CC, "Centroid");
        centVox = stats.Centroid;                  % [x y z] = [col row slice]，1-based
        vox0    = centVox - 1;                     % 相对索引(0-based)

        x_mm = vox0(:,1) * voxelSize(1);
        y_mm = vox0(:,2) * voxelSize(2);
        z_mm = vox0(:,3) * voxelSize(3);

        % ====== 8) 剔除离群值（对体积用 IQR/四分位法）=====
        app.lbl_Status.Text = '状态：正在剔除离群值...';
        drawnow;

        yAll = double(roiVolume);
        try
            isOut = isoutlier(yAll, 'quartiles');
        catch
            q1 = prctile(yAll, 25);
            q3 = prctile(yAll, 75);
            iqrV = q3 - q1;
            lower = q1 - 1.5 * iqrV;
            upper = q3 + 1.5 * iqrV;
            isOut = (yAll < lower) | (yAll > upper);
        end

        roiLabels_f = roiLabels(~isOut);
        roiVolume_f = roiVolume(~isOut);
        x_mm_f      = x_mm(~isOut);
        y_mm_f      = y_mm(~isOut);
        z_mm_f      = z_mm(~isOut);

        numRemoved = sum(isOut);

        % 极端情况：全被剔除则退回不剔除
        if isempty(roiLabels_f)
            roiLabels_f = roiLabels;
            roiVolume_f = roiVolume;
            x_mm_f = x_mm; y_mm_f = y_mm; z_mm_f = z_mm;
            isOut = false(size(roiLabels));
            numRemoved = 0;
        end

        % ====== 9) 建表：增加 ClusterID 列（先填 NaN，等聚类按钮来算）=====
        ClusterID = nan(height(roiLabels_f), 1);

        app.T = table(roiLabels_f, roiVolume_f, x_mm_f, y_mm_f, z_mm_f, ClusterID, ...
            'VariableNames', {'ROI_Label','Volume_mm3','x_mm','y_mm','z_mm','ClusterID'});

        app.UITable.Data = app.T;
        app.UITable.ColumnName = {'Label','Volume(mm^3)','x(mm)','y(mm)','z(mm)','ClusterID'};

        % ====== 10) 2D 散点图（渐变：体积越大点越大颜色越浓）=====
        app.lbl_Status.Text = '状态：正在绘制体积散点图（渐变颜色）...';
        drawnow;

        cla(app.ax_Histogram);
        app.ax_Histogram.Visible = 'on';

        x = double(roiLabels_f);
        y = double(roiVolume_f);

        yMin = min(y); yMax = max(y);
        if yMax > yMin
            s = 20 + (y - yMin) / (yMax - yMin) * (200 - 20);
        else
            s = 80 * ones(size(y));
        end

        baseColor  = [0 0.4470 0.7410];
        lightColor = 1 - (1 - baseColor) * 0.25;
        n = 256;
        cmap = [linspace(lightColor(1), baseColor(1), n)', ...
                linspace(lightColor(2), baseColor(2), n)', ...
                linspace(lightColor(3), baseColor(3), n)'];

        scatter(app.ax_Histogram, x, y, s, y, 'filled');
        colormap(app.ax_Histogram, cmap);
        caxis(app.ax_Histogram, [yMin yMax]);
        grid(app.ax_Histogram, 'on');
        xlabel(app.ax_Histogram, 'ROI 编号 (Label)');
        ylabel(app.ax_Histogram, '体积 (mm^3)');
        title(app.ax_Histogram, sprintf('ROI 体积散点图（剔除离群值 %d 个）', numRemoved));
        app.ax_Histogram.XLim = [0, max(x)+1];
        colorbar(app.ax_Histogram);

        % ====== 11) 缓存（用于联动/聚类/3D）=====
        app.roiLabelSeparated = labelSeparated;
        app.roiBestSlice      = bestSlice;

        % 缓存“全部ROI”（不剔除）用于联动高亮（更稳）
        app.roiLabels     = roiLabels;
        app.roiVolumeMM3  = roiVolume;
        app.roiCentroidMM = [x_mm, y_mm, z_mm];
        app.roiIsOutlier  = isOut;

        % ====== 12) 完成状态 ======
        app.lbl_Status.Text = sprintf('状态：完成！ROI=%d（剔除离群值 %d）| Slice=%d | %s', ...
            CC.NumObjects, numRemoved, bestSlice, fileName);
        drawnow;

    catch ME
        uialert(app.UIFigure, ME.message, '读取/计算错误');
        app.lbl_Status.Text = '状态：失败（检查文件/维度/工具箱）';
        drawnow;
    end

        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
      % ====== 0) 检查数据是否存在 ======
    if isempty(app.T) || ~istable(app.T) || height(app.T) == 0
        uialert(app.UIFigure, '请先点击“计算体素大小”生成 ROI 表格后再聚类。', '提示');
        app.lbl_Status.Text = '状态：聚类失败（没有 ROI 数据）';
        drawnow;
        return;
    end

    needVars = {'ROI_Label','Volume_mm3','x_mm','y_mm','z_mm'};
    if ~all(ismember(needVars, app.T.Properties.VariableNames))
        uialert(app.UIFigure, '表格缺少必要列：ROI_Label/Volume_mm3/x_mm/y_mm/z_mm。', '提示');
        app.lbl_Status.Text = '状态：聚类失败（表格列缺失）';
        drawnow;
        return;
    end

    % ====== 1) 弹窗输入 K（默认 3） ======
    answer = inputdlg({'请输入需要聚成几类 K（默认 3）:'}, '聚类分析', [1 40], {'3'});
    if isempty(answer) || isempty(strtrim(answer{1}))
        K = 3;  % 用户取消/没填 -> 默认 3
    else
        K = str2double(answer{1});
        if ~isfinite(K) || K < 1
            K = 3;
        end
        K = round(K);
    end

    % K 不能超过样本数
    N = height(app.T);
    if K > N
        K = N;
    end

    app.lbl_Status.Text = sprintf('状态：正在进行 K-means 聚类（K=%d）...', K);
    drawnow;

    % ====== 2) 构造特征：位置 + log1p(体积) ======
    X = [double(app.T.x_mm), double(app.T.y_mm), double(app.T.z_mm), ...
         log1p(double(app.T.Volume_mm3))];

    % 标准化（不算“数据清理”，只是尺度统一；否则体积会主导）
    Xz = (X - mean(X,1)) ./ (std(X,0,1) + eps);

    % ====== 3) K-means 聚类 ======
    % Replicates 多跑几次更稳，MaxIter 防止不收敛
    try
        cid = kmeans(Xz, K, 'Replicates', 10, 'MaxIter', 500);
    catch ME
        uialert(app.UIFigure, ME.message, 'K-means 失败（可能缺少工具箱）');
        app.lbl_Status.Text = '状态：聚类失败';
        drawnow;
        return;
    end

    % ====== 4) 写回表格 ClusterID ======
    if ~ismember('ClusterID', app.T.Properties.VariableNames)
        app.T.ClusterID = cid;
        % 显示列名按你截图
        app.UITable.ColumnName = {'Label','Volume(mm^3)','x(mm)','y(mm)','z(mm)','ClusterID'};
    else
        app.T.ClusterID = cid;
    end
    app.UITable.Data = app.T;

  % ====== 5) 3D 可视化（scatter3）：确保取到的是数值 ======
cla(app.ax_Histogram, 'reset');          % reset 很关键
app.ax_Histogram.Visible = 'on';

% 一定从 app.T 取（table），不要从 UITable.Data 取（可能是 cell）
x = app.T.x_mm;
y = app.T.y_mm;
z = app.T.z_mm;
v = app.T.Volume_mm3;

% 强制转 double（哪怕它是 single/int/cell）
x = double(x);  y = double(y);  z = double(z);  v = double(v);

% 如果你数据里本来就没 NaN，这里不会改变任何结果；
% 但如果存在 NaN，scatter3 会直接“看不到点”
% （不想“清理”可以注释掉下一行，仅用于调试）
% disp([x y z v]);

% 点大小（保证可见）
vMin = min(v); vMax = max(v);
if vMax > vMin
    s = 60 + (v - vMin) / (vMax - vMin) * (400 - 60);
else
    s = 200 * ones(size(v));
end

scatter3(app.ax_Histogram, x, y, z, s, double(app.T.ClusterID), 'filled');

grid(app.ax_Histogram, 'on');
xlabel(app.ax_Histogram, 'x (mm)');
ylabel(app.ax_Histogram, 'y (mm)');
zlabel(app.ax_Histogram, 'z (mm)');
title(app.ax_Histogram, sprintf('3D ROI 聚类结果（K-means, K=%d）', K));
view(app.ax_Histogram, 3);

colorbar(app.ax_Histogram);

        end

        % Cell selection callback: UITable
        function UITableCellSelection2(app, event)
            indices = event.Indices;
            % 0) 基本检查
    if isempty(event) || ~isprop(event,'Indices') || isempty(event.Indices)
        return;
    end
    if isempty(app.roiLabelSeparated)
        app.lbl_Status.Text = '状态：没有 ROI 分离结果，请先点“计算体素大小”';
        return;
    end

    r = event.Indices(1);   % 被点中的“显示行号”

    % 1) 从 UITable 当前显示的数据里取 ROI ID（关键！！）
    data = app.UITable.Data;

    if istable(data)
        % 你表的真实变量名可能是 ROI_Label，也可能是 Label/ROI
        if ismember('ROI_Label', data.Properties.VariableNames)
            roiID = data.ROI_Label(r);
        elseif ismember('Label', data.Properties.VariableNames)
            roiID = data.Label(r);
        elseif ismember('ROI', data.Properties.VariableNames)
            roiID = data.ROI(r);
        else
            % 没找到就默认取第一列
            roiID = data{r,1};
        end
    else
        % 若 Data 变成了 cell/矩阵，默认第一列就是 ROI 编号
        roiID = data(r,1);
        if iscell(roiID), roiID = roiID{1}; end
    end
    roiID = double(roiID);

    % 2) 找该 ROI 像素最多的 slice
    volsPerSlice = squeeze(sum(sum(app.roiLabelSeparated == roiID, 1), 2));
    if isempty(volsPerSlice) || all(volsPerSlice == 0)
        app.lbl_Status.Text = sprintf('状态：ROI %d 在任何切片中都不存在', roiID);
        return;
    end
    [~, z] = max(volsPerSlice);

    mask = (app.roiLabelSeparated(:,:,z) == roiID);

    % 3) 彻底清空并重置坐标轴（避免“看起来不变”）
    cla(app.ax_Processed, 'reset');
    app.ax_Processed.Visible = 'on';

    % 4) 显示（你想黑底白标注，就这样）
    imagesc(app.ax_Processed, mask);
    colormap(app.ax_Processed, gray(256));
    app.ax_Processed.CLim = [0 1];
    axis(app.ax_Processed, 'image');
    app.ax_Processed.Visible = 'off';
    title(app.ax_Processed, sprintf('ROI %d（Slice %d）', roiID, z));

    % 5) 自动放大到 ROI（否则 ROI 很小就像“没变化”）
    [rr, cc] = find(mask);
    if ~isempty(rr)
        pad = 25;
        r1 = max(min(rr)-pad, 1);
        r2 = min(max(rr)+pad, size(mask,1));
        c1 = max(min(cc)-pad, 1);
        c2 = min(max(cc)+pad, size(mask,2));
        app.ax_Processed.XLim = [c1 c2];
        app.ax_Processed.YLim = [r1 r2];
    end

    app.lbl_Status.Text = sprintf('状态：选中 ROI %d | Slice %d', roiID, z);
    drawnow;
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            [file, path] = uiputfile('*.xlsx');
            if isequal(file,0)
                return;
            end
            writetable(app.T, fullfile(path, file));
        end

        % Callback function
        function Button_6Pushed(app, event)
              [f,p] = uigetfile( ...
        {'*.nii;*.nii.gz','NIfTI files (*.nii, *.nii.gz)'}, ...
        'Select T1-weighted NIfTI');

    if isequal(f,0)
        app.t1Path = "";
        app.NotAvailableLabel.Text = "Not Available...";
        app.NotAvailableLabel.FontColor = [0.85 0 0];
        app.setStatus("T1w not imported");
        return;
    end

    app.t1Path = string(fullfile(p,f));
    app.NotAvailableLabel.Text = "Imported";
    app.NotAvailableLabel.FontColor = [0 0.6 0];
    app.setStatus("T1w NIfTI imported");
        end

        % Callback function
        function Button_7Pushed(app, event)
             [f,p] = uigetfile( ...
        {'*.nii;*.nii.gz','NIfTI files (*.nii, *.nii.gz)'}, ...
        'Select FLAIR NIfTI');

    if isequal(f,0)
        app.flairPath = "";
        app.NotAvailableLabel_2.Text = "Not Available...";
        app.NotAvailableLabel_2.FontColor = [0.85 0 0];
        app.setStatus("FLAIR not imported");
        return;
    end

    app.flairPath = string(fullfile(p,f));
    app.NotAvailableLabel_2.Text = "Imported";
    app.NotAvailableLabel_2.FontColor = [0 0.6 0];
    app.setStatus("FLAIR NIfTI imported");
        end

        % Callback function
        function Button_8Pushed(app, event)
             [f,p] = uigetfile( ...
        {'*.nii;*.nii.gz','NIfTI mask (*.nii, *.nii.gz)'}, ...
        'Select ROI Ground Truth (NIfTI)');

    if isequal(f,0)
        app.roiPath = "";
        app.NotAvailableLabel_3.Text = "Not Available...";
        app.NotAvailableLabel_3.FontColor = [0.85 0 0];
        app.setStatus("ROI not imported");
        return;
    end

    app.roiPath = string(fullfile(p,f));
    app.NotAvailableLabel_3.Text = "Imported";
    app.NotAvailableLabel_3.FontColor = [0 0.6 0];
    app.setStatus("ROI NIfTI imported");
        end

        % Callback function
        function StartSegmentationButtonPushed(app, event)

% =======================
% Start Segmentation
% =======================

% ---- 1) 选择算法 ----
if app.SPM12TissueSegmentationT1onlyButton.Value
    algo = "SPM12_T1";
elseif app.LSTLesionPredictionFLAIRonlyButton.Value
    algo = "LST_LPA_FLAIR";
elseif app.LSTLesionGrowthT1FLAIRButton.Value
    algo = "LST_LGA_T1_FLAIR";
else
    uialert(app.UIFigure, "Please select an algorithm.", "No Selection");
    return;
end

% ---- 2) 输入检查 ----
needT1 = (algo=="SPM12_T1") || (algo=="LST_LGA_T1_FLAIR");
needFL = (algo=="LST_LPA_FLAIR") || (algo=="LST_LGA_T1_FLAIR");

if needT1 && strlength(app.t1Path)==0
    uialert(app.UIFigure, "T1w NIfTI is required.", "Missing Input");
    return;
end
if needFL && strlength(app.flairPath)==0
    uialert(app.UIFigure, "FLAIR NIfTI is required.", "Missing Input");
    return;
end

if needT1 && ~app.isNiftiPath(app.t1Path)
    uialert(app.UIFigure, "T1w must be *.nii or *.nii.gz.", "Invalid Format");
    return;
end
if needFL && ~app.isNiftiPath(app.flairPath)
    uialert(app.UIFigure, "FLAIR must be *.nii or *.nii.gz.", "Invalid Format");
    return;
end

% ---- 3) 输出目录（保留原逻辑）----
ts = datestr(now,'yyyymmdd_HHMMSS');
baseDir = app.saveBaseDir;
if strlength(baseDir)==0 || ~exist(char(baseDir),'dir')
    baseDir = string(tempdir);
end

app.segOutDir = string(fullfile(char(baseDir), "SegResult_" + algo + "_" + ts));
if ~exist(char(app.segOutDir),'dir')
    mkdir(char(app.segOutDir));
end

% reset result holders
app.segMaskPath = "";
app.segMaskVol  = [];

app.setStatus("Segmentation started: " + algo);

try
    switch algo

        % ===== SPM12 Tissue Segmentation (T1 only) =====
        case "SPM12_T1"
            app.ensureSPM12();
            tissuePaths = app.runSPM12_TissueSeg(app.t1Path, app.segOutDir);
            app.segTissuePaths = tissuePaths;

            uialert(app.UIFigure, ...
                "SPM12 finished." + newline + ...
                "c1~c6 tissue probability maps generated." + newline + ...
                "These are NOT lesion masks.", ...
                "SPM12 Output");

            % 预览白质 c2
            wmVol = niftiread(char(tissuePaths(2)));
            z = round(size(wmVol,3)/2);
            imagesc(app.ax_Processed, wmVol(:,:,z));
            colormap(app.ax_Processed, gray);
            axis(app.ax_Processed,'image');
            app.ax_Processed.Visible = 'off';
            title(app.ax_Processed, 'SPM12 WM (c2)');

            app.setStatus("SPM12 finished.");
            return;  % SPM12 不输出 lesion mask，这里直接结束

        % ===== LST LPA (FLAIR only) =====
        case "LST_LPA_FLAIR"
            app.ensureLST();

            app.setStatus("LST-LPA running...");
            % runLST_LPA 内部会把输入复制到 app.segOutDir，并在该目录产出结果
            app.segMaskPath = string(app.runLST_LPA(app.flairPath, app.segOutDir));

        % ===== LST LGA (T1 + FLAIR) =====
        case "LST_LGA_T1_FLAIR"
            app.ensureLST();

            app.setStatus("LST-LGA running...");
            % runLST_LGA 内部会：运行 + 记录输出 +（若设置 saveBaseDir）移动产物并更新 app.segOutDir/app.segMaskPath
            outMask = app.runLST_LGA(app.t1Path, app.flairPath);

            % 兼容：有的版本 runLST_LGA 已经把 app.segMaskPath 设好了
            if strlength(app.segMaskPath)==0
                app.segMaskPath = string(outMask);
            end

            % 兼容：runLST_LGA 可能会把 app.segOutDir 改为 LGA_时间戳目录
            % 若它已更新，则以它为准；否则保留本函数创建的 segOutDir
            if strlength(app.segOutDir)==0
                app.segOutDir = string(fileparts(char(app.segMaskPath)));
            end
    end

    % ---- 4) 读取并预览 mask（LPA/LGA 共用）----
    if strlength(app.segMaskPath)==0 || exist(char(app.segMaskPath),'file')~=2
        error("Segmentation mask not found. segMaskPath=%s", string(app.segMaskPath));
    end

    app.segMaskVol = (niftiread(char(app.segMaskPath)) > 0);

    % 预览一次
    app.previewMaskOnAxes(app.segMaskVol, app.ax_Processed, "Segmentation Preview");

    app.setStatus("Segmentation finished: " + algo);
    uialert(app.UIFigure, "Segmentation finished: " + algo, "Done");

catch ME
    disp(getReport(ME,'extended'));
    uialert(app.UIFigure, ME.message, "Segmentation Failed");
    app.setStatus("Segmentation failed: " + algo);
end


        end

        % Callback function
        function AccuracyValidationButtonPushed(app, event)

% =======================
% Accuracy Validation (Align pred->GT space + robust threshold)
% =======================

thrFixed = app.thrFixed0109EditField.Value;   % 0.1~0.9
if isempty(thrFixed) || ~isfinite(thrFixed)
    thrFixed = 0.5;
end

% ---- 1) 检查 GT ----
gtOk = (strlength(app.roiPath) > 0) && (exist(char(app.roiPath),'file') == 2);
if ~gtOk
    uialert(app.UIFigure, "ROI Ground Truth is required.", "Missing ROI");
    app.setStatus("Validation cancelled (missing GT).");
    return;
end

% ---- 2) 检查 Prediction ----
maskOk = (strlength(app.segMaskPath) > 0) && (exist(char(app.segMaskPath),'file') == 2);
if ~maskOk
    [f, pth] = uigetfile({'*.nii;*.nii.gz','NIfTI (*.nii, *.nii.gz)'}, ...
        'Select prediction output (mask/probability map)');
    if isequal(f,0)
        app.setStatus("Validation cancelled (no prediction selected).");
        return;
    end
    app.segMaskPath = string(fullfile(pth, f));
end

app.setStatus("Validation started.");

try
    % ---- 3.1) 读取 GT ----
    gtPath = char(app.roiPath);
    gtInfo = niftiinfo(gtPath);
    gtRaw  = niftiread(gtInfo);

    % GT 强制二值
    gt = (double(gtRaw) > 0);
    gt = logical(gt);

    % ---- 3.2) 读取 Prediction (RAW) ----
    predPath = char(app.segMaskPath);
    predInfo = niftiinfo(predPath);
    predRaw  = niftiread(predInfo);

    % 统一 double（配准/阈值稳）
    p = double(predRaw);

    % 文件名提示（LPA输出常为概率图）
    [~, fname, ~] = fileparts(predPath);
    isPleLpa = contains(lower(fname), "ples_lpa");

    % 判定是否“本来就是二值mask”
    u = unique(p(:));
    u = u(~isnan(u));
    isBinaryLike = (numel(u) <= 3) && all(ismember(u, [0 1]));

    % ====== Debug：RAW ======
    disp("==== PRED RAW DEBUG ====");
    disp("PredRaw size    = " + mat2str(size(p)));
    disp("PredRaw min/max = " + min(p(:)) + " / " + max(p(:)));
    disp("PredRaw nnz(>0) = " + nnz(p > 0));
    disp("GT size         = " + mat2str(size(gt)));
    disp("GT nnz          = " + nnz(gt));

    % ---- 3.3) 对齐 Prediction -> GT 空间（阈值前）----
    needAlign = ~isequal(size(p), size(gt));
    if isfield(predInfo,'PixelDimensions') && isfield(gtInfo,'PixelDimensions')
        pdm = double(predInfo.PixelDimensions(1:min(3,end)));
        pdf = double(gtInfo.PixelDimensions(1:min(3,end)));
        if numel(pdm)==3 && numel(pdf)==3
            needAlign = needAlign || any(abs(pdm - pdf) > 1e-6);
        end
    end

    if needAlign
        app.setStatus("Aligning prediction to GT space...");

        interp = "linear";
        if isBinaryLike
            interp = "nearest";
        end

        % !!! 关键：传的是体数据 p + 两个 info struct
        p = app.alignPredToGT(p, predInfo, gtInfo, interp);

        disp("==== AFTER ALIGN DEBUG ====");
        disp("Aligned p min/max = " + min(p(:)) + " / " + max(p(:)));
        disp("Aligned p nnz(>0) = " + nnz(p > 0));
    end

    % ---- 3.4) 阈值化（在 GT 空间上做）----
    mn = min(p(:)); mx = max(p(:));

    if isBinaryLike
        pred = (p > 0.5);
        thrInfo = "binary mask (after alignment)";
    else
        if (mn >= 0.0) && (mx <= 1.0)
            % thrFixed 太高 -> 预测全 0 的兜底
            if mx < thrFixed
                newThr = max(0.05, 0.5*mx);
                uialert(app.UIFigure, ...
                    sprintf("thrFixed=%.2f too high (max=%.3f). Auto set to %.3f.", thrFixed, mx, newThr), ...
                    "Threshold adjusted");
                thrFixed = newThr;
            end

            pred = (p >= thrFixed);
            if isPleLpa
                thrInfo = "ples_lpa prob map, thr=" + string(thrFixed);
            else
                thrInfo = "prob map [0,1], thr=" + string(thrFixed);
            end
        else
            % 非概率图：归一化后 Otsu
            p01 = (p - mn) / (mx - mn + eps);
            thr01 = graythresh(p01);
            pred = (p01 >= thr01);
            thrInfo = "non-prob map, Otsu thr01=" + string(thr01);
        end
    end

    pred = logical(pred);  % 强制 logical，避免类型乱

    % ====== Debug：BIN ======
    disp("==== PRED BIN DEBUG ====");
    disp("thrInfo   = " + thrInfo);
    disp("Pred nnz  = " + nnz(pred));
    disp("Overlap   = " + nnz(pred & gt));

    % 如果还是全 0，直接提示（一般是对齐跑飞/Transform 缺失）
    if nnz(pred) == 0 && nnz(p > 0) > 0
        uialert(app.UIFigure, ...
            "Prediction becomes all-zero AFTER threshold in GT space. " + newline + ...
            "Likely原因：对齐后强度被稀释/阈值过高/Transform信息异常。请查看控制台 DEBUG。", ...
            "All-zero prediction");
    end

    % ---- 3.5) 指标计算 ----
    [dic, iou, precision, recall, f1] = app.calcSegMetrics(pred, gt);

    % ---- 4) 预览 ----
    app.previewMaskOnAxes(pred, app.ax_Processed, "Prediction (" + thrInfo + ")");

    % ---- 5) UI表格 ----
    dic_good = 0.70; dic_fair = 0.40;
    iou_good = 0.50; iou_fair = 0.25;
    pr_good  = 0.70; pr_fair  = 0.40;
    re_good  = 0.70; re_fair  = 0.40;
    f1_good  = 0.70; f1_fair  = 0.40;

    app.UITable2.Data = {
        'Dice',      double(dic),       app.grade3(dic,      dic_good, dic_fair);
        'IoU',       double(iou),       app.grade3(iou,      iou_good, iou_fair);
        'Precision', double(precision), app.grade3(precision,pr_good,  pr_fair);
        'Recall',    double(recall),    app.grade3(recall,   re_good,  re_fair);
        'F1',        double(f1),        app.grade3(f1,       f1_good,  f1_fair);
    };

    app.setStatus("Validation finished. (" + thrInfo + ")");

catch ME
    disp(getReport(ME, 'extended'));
    uialert(app.UIFigure, ME.message, "Validation Failed");
    app.setStatus("Validation failed.");
end


        end

        % Callback function
        function Button_9Pushed(app, event)
            outDir = uigetdir(pwd, '选择保存输出的文件夹');
    if isequal(outDir, 0)
        app.saveBaseDir = "";

        % 红色提示：未选择
        app.NotAvailableLabel_4.Text = "Not Available...";
        app.NotAvailableLabel_4.FontColor = [0.85 0 0];

        app.setStatus("Save path not selected");
        return;
    end

    app.saveBaseDir = string(outDir);

    % 绿色提示：已选择
    app.NotAvailableLabel_4.Text = "Selected";
    app.NotAvailableLabel_4.FontColor = [0 0.6 0];

    app.setStatus("Save path selected: " + app.saveBaseDir);
        end

        % Clicked callback: UITable
        function UITableClicked(app, event)
            displayRow = event.InteractionInformation.DisplayRow;
            displayColumn = event.InteractionInformation.DisplayColumn;
            
        end

        % Value changed function: txt_Stats
        function txt_StatsValueChanged(app, event)
            value = app.txt_Stats.Value;
            
        end

        % Callback function
        function btn_browse_spmButtonPushed(app, event)
            path = uigetdir('', '选择SPM12安装文件夹');
            if path ~= 0
                app.edit_spm.Value = path;
                app.textarea_log.Value{1} = sprintf('已选择SPM12路径: %s\n', path);
            end
        end

        % Callback function
        function btn_browse_image_to_alignButtonPushed(app, event)
            [file, path] = uigetfile('*.nii', '选择 Image to Align');
            if file ~= 0
                full_path = fullfile(path, file);
                app.edit_image_to_align.Value = full_path;
                app.textarea_log.Value{1} = sprintf('已选择 Image to Align: %s\n', full_path);
            end
        end

        % Callback function
        function btn_browse_images_to_writeButtonPushed(app, event)
            [write_files, write_path] = uigetfile( ...
                '*.nii', ...
                '选择 Images to Write', ...
                'MultiSelect', 'on');
            if write_files ~= 0
                if iscell(write_files)
                    full_paths = cell(1, length(write_files));
                    for i = 1:length(write_files)
                        full_paths{i} = fullfile(write_path, write_files{i});
                    end
                    app.edit_images_to_write.Value = sprintf('%s; ', full_paths{:});
                else
                    full_path = fullfile(write_path, write_files);
                    app.edit_images_to_write.Value = full_path;
                end
                app.textarea_log.Value{1} = sprintf('已选择 Images to Write: %s\n', app.edit_images_to_write.Value);
            end
        end

        % Callback function
        function btn_browse_tissue_prob_mapButtonPushed(app, event)
            [file, path] = uigetfile('TPM.nii', '选择 Tissue probability map');
            if file ~= 0
                full_path = fullfile(path, file);
                app.edit_tissue_prob_map.Value = full_path;
                app.textarea_log.Value{1} = sprintf('已选择 Tissue probability map: %s\n', full_path);
            end

        end

        % Callback function
        function btn_run_normalizeButtonPushed(app, event)
            
            spm_path = app.edit_spm.Value;
            image_to_align = app.edit_image_to_align.Value;
            images_to_write_str = app.edit_images_to_write.Value;
            tissue_prob_map = app.edit_tissue_prob_map.Value;

            % --- 1. 基础检查 ---
            if isempty(spm_path)
                if exist('spm', 'file') == 2
                    spm_path = fileparts(which('spm'));
                    app.edit_spm.Value = spm_path;
                    app.textarea_log.Value{1} = sprintf('自动检测到SPM12路径: %s\n', spm_path);
                else
                    errordlg('请先选择SPM12路径！');
                    return;
                end
            end
            if isempty(image_to_align)
                errordlg('请先选择 Image to Align！');
                return;
            end
            if isempty(images_to_write_str)
                errordlg('请先选择 Images to Write！');
                return;
            end
            if isempty(tissue_prob_map)
                errordlg('请先选择 Tissue probability map！');
                return;
            end

            addpath(spm_path);
            spm('defaults', 'FMRI');
            spm_jobman('initcfg');

            % --- 2. 处理文件列表 ---
            images_to_write = strsplit(images_to_write_str, '; ');
            images_to_write = images_to_write(~cellfun(@isempty, images_to_write));
            for i = 1:length(images_to_write)
                images_to_write{i} = strtrim(images_to_write{i});
            end

            % --- 3. 设置默认参数  ---
            % 默认 Warping Regularisation [0 0.001 0.5 0.05 0.2]
            default_warp_reg = [0, 0.001, 0.5, 0.05, 0.2]; 
            
            % 默认 Bounding box [-90 -126 -72; 90 90 106]
            default_bb = [-90 -126 -72; 90 90 106]; 

            % --- 4. 生成日志 ---
            log_text = sprintf('========================================\n');
            log_text = [log_text, sprintf('   SPM12 Normalize (Estimate & Write)\n')];
            log_text = [log_text, sprintf('========================================\n')];
            log_text = [log_text, sprintf('SPM12路径: %s\n', spm_path)];
            log_text = [log_text, sprintf('Image to Align: %s\n', image_to_align)];
            log_text = [log_text, sprintf('Images to Write: %d 个\n', length(images_to_write))];
            for i = 1:length(images_to_write)
                log_text = [log_text, sprintf('  %d. %s\n', i, images_to_write{i})];
            end
            log_text = [log_text, sprintf('Tissue probability map: %s\n', tissue_prob_map)];
            log_text = [log_text, sprintf('\n参数设置:\n')];
            log_text = [log_text, sprintf('  Bias regularisation: %.6f\n', app.edit_bias_reg.Value)];
            log_text = [log_text, sprintf('  Bias FWHM: %.1f mm\n', app.edit_bias_fwhm.Value)];
            log_text = [log_text, sprintf('  Affine Regularisation: %s\n', app.dropdown_affine_reg.Value)];
            
            % 日志显示默认值
            log_text = [log_text, sprintf('  Warping Regularisation: [%.4f, %.4f, %.4f, %.4f, %.4f] (默认)\n', ...
                default_warp_reg)];
            
            log_text = [log_text, sprintf('  Smoothness: %.1f\n', app.edit_smoothness.Value)];
            log_text = [log_text, sprintf('  Sampling distance: %.1f\n', app.edit_sampling_dist.Value)];
            log_text = [log_text, sprintf('  Voxel sizes: [%.1f, %.1f, %.1f]\n', ...
                app.edit_voxel_size1.Value, app.edit_voxel_size2.Value, app.edit_voxel_size3.Value)];
            
            % 日志显示默认值
            log_text = [log_text, sprintf('  Bounding box: [%.0f %.0f %.0f; %.0f %.0f %.0f] (默认)\n', ...
                default_bb(1,:), default_bb(2,:))];
            
            log_text = [log_text, sprintf('  Interpolation: %s\n', app.dropdown_interp.Value)];
            log_text = [log_text, sprintf('  Filename Prefix: %s\n', app.edit_prefix.Value)];
            log_text = [log_text, sprintf('\n开始Normalise: Estimate & Write过程...\n')];
            app.textarea_log.Value{1} = log_text;

            % --- 5. 执行 SPM ---
            try
                job = [];
                job.subj(1).vol = {image_to_align};
                job.subj(1).resample = images_to_write;
                
                % 设置 Estimation Options
                job.eoptions.biasreg = app.edit_bias_reg.Value;
                job.eoptions.biasfwhm = app.edit_bias_fwhm.Value;
                job.eoptions.tpm = {tissue_prob_map};
                job.eoptions.affreg = app.dropdown_affine_reg.Value;
                
                % 使用变量 default_warp_reg
                job.eoptions.reg = default_warp_reg;
                
                job.eoptions.fwhm = app.edit_smoothness.Value;
                job.eoptions.samp = app.edit_sampling_dist.Value;
                
                % 设置 Writing Options
                % 使用变量 default_bb...
                job.woptions.bb = default_bb;
                
                job.woptions.vox = [app.edit_voxel_size1.Value, app.edit_voxel_size2.Value, app.edit_voxel_size3.Value];
                
                % 设置插值方法
                interp_map = containers.Map(...
                    {'Nearest Neighbour', 'Trilinear', '2nd Degree B-Spline', '3rd Degree B-Spline', '4th Degree B-Spline'}, ...
                    [0, 1, 2, 3, 4]);
                job.woptions.interp = interp_map(app.dropdown_interp.Value);
                job.woptions.prefix = app.edit_prefix.Value;
                
                spm_run_norm(job);
                
                log_text = [app.textarea_log.Value{1}, sprintf('\n配准完成！\n')];
                app.textarea_log.Value{1} = log_text;
            catch ME
                log_text = [app.textarea_log.Value{1}, sprintf('\n配准失败：%s\n', ME.message)];
                app.textarea_log.Value{1} = log_text;
                errordlg(['配准失败：' ME.message]);
            end
        end

        % Callback function
        function btn_browse_spm_2ButtonPushed(app, event)
              path = uigetdir('', '选择SPM12安装文件夹');
            if path ~= 0
                app.edit_spm_2.Value = path;
                app.textarea_log.Value{1} = sprintf('已选择SPM12路径: %s\n', path);
            end
        end

        % Callback function
        function btn_browse_reference_imageButtonPushed(app, event)
              [file, path] = uigetfile( ...
                {'*.nii;*.nii.gz', 'NIfTI 文件 (*.nii, *.nii.gz)'; ...
                '*.*', '所有文件 (*.*)'}, ...
                '选择 Reference Image');
            if file ~= 0
                full_path = fullfile(path, file);
                app.edit_reference_image.Value = full_path;
                app.textarea_log.Value{1} = sprintf('已选择 Reference Image: %s\n', full_path);
            end

        end

        % Callback function
        function btn_browse_source_imageButtonPushed(app, event)
              [file, path] = uigetfile( ...
                {'*.nii;*.nii.gz', 'NIfTI 文件 (*.nii, *.nii.gz)'; ...
                '*.*', '所有文件 (*.*)'}, ...
                '选择 Source Image');
            if file ~= 0
                full_path = fullfile(path, file);
                app.edit_source_image.Value = full_path;
                app.textarea_log.Value{1} = sprintf('已选择 Source Image: %s\n', full_path);
            end

        end

        % Callback function
        function btn_browse_other_imagesButtonPushed(app, event)
            
            [other_files, other_path] = uigetfile( ...
                {'*.nii;*.nii.gz', 'NIfTI 文件 (*.nii, *.nii.gz)'; ...
                '*.*', '所有文件 (*.*)'}, ...
                '选择 Other Images', ...
                'MultiSelect', 'on');
            if other_files ~= 0
                if iscell(other_files)
                    full_paths = cell(1, length(other_files));
                    for i = 1:length(other_files)
                        full_paths{i} = fullfile(other_path, other_files{i});
                    end
                    app.edit_other_images.Value = sprintf('%s; ', full_paths{:});
                else
                    full_path = fullfile(other_path, other_files);
                    app.edit_other_images.Value = full_path;
                end
                app.textarea_log.Value{1} = sprintf('已选择 Other Images: %s\n', app.edit_other_images.Value);
            end
        end

        % Callback function
        function btn_run_coregisterButtonPushed(app, event)
            
            spm_path = app.edit_spm_2.Value;
            reference_image = app.edit_reference_image.Value;
            source_image = app.edit_source_image.Value;

            % --- 1. 基础检查 ---
            if isempty(spm_path)
                if exist('spm', 'file') == 2
                    spm_path = fileparts(which('spm'));
                    app.edit_spm_2.Value = spm_path;
                    app.textarea_log.Value{1} = sprintf('自动检测到SPM12路径: %s\n', spm_path);
                else
                    errordlg('请先选择SPM12路径！');
                    return;
                end
            end
            if isempty(reference_image)
                errordlg('请先选择 Reference Image！');
                return;
            end
            if isempty(source_image)
                errordlg('请先选择 Source Image！');
                return;
            end

            addpath(spm_path);
            spm('defaults', 'FMRI');
            spm_jobman('initcfg');

            % --- 2. 定义默认 Tolerances  ---
            default_tol = [0.02, 0.02, 0.02, 0.001, 0.001, 0.001, 0.01, 0.01, 0.01, 0.001, 0.001, 0.001];

            % --- 3. 生成日志 ---
            log_text = sprintf('========================================\n');
            log_text = [log_text, sprintf('   SPM12 Coregister (Estimate & Write)\n')];
            log_text = [log_text, sprintf('========================================\n')];
            log_text = [log_text, sprintf('SPM12路径: %s\n', spm_path)];
            log_text = [log_text, sprintf('Reference Image: %s\n', reference_image)];
            log_text = [log_text, sprintf('Source Image: %s\n', source_image)];
            log_text = [log_text, sprintf('\n参数设置:\n')];
            log_text = [log_text, sprintf('  Objective Function: %s\n', app.dropdown_obj_func.Value)];
            log_text = [log_text, sprintf('  Separation: [%.1f, %.1f]\n', app.edit_separation1.Value, app.edit_separation2.Value)];
            
            % 日志这里不再从界面读取，而是直接显示 default_tol 变量
            log_text = [log_text, sprintf('  Tolerances: [%.3f %.3f %.3f %.4f %.4f %.4f %.3f %.3f %.3f %.4f %.4f %.4f] (默认)\n', ...
                default_tol)];
                
            log_text = [log_text, sprintf('  Histogram Smoothing: [%.1f, %.1f]\n', app.edit_hist_smooth1.Value, app.edit_hist_smooth2.Value)];
            log_text = [log_text, sprintf('  Interpolation: %s\n', app.dropdown_coreg_interp.Value)];
            log_text = [log_text, sprintf('  Wrapping: %s\n', app.dropdown_wrapping.Value)];
            log_text = [log_text, sprintf('  Masking: %s\n', app.dropdown_masking.Value)];
            log_text = [log_text, sprintf('  Filename Prefix: %s\n', app.edit_coreg_prefix.Value)];
            
            % 处理Other Images日志
            other_images_str = app.edit_other_images.Value;
            if ~isempty(other_images_str)
                other_images = strsplit(other_images_str, '; ');
                other_images = other_images(~cellfun(@isempty, other_images));
                log_text = [log_text, sprintf('  Other Images: %d 个\n', length(other_images))];
                for i = 1:length(other_images)
                    log_text = [log_text, sprintf('    %d. %s\n', i, strtrim(other_images{i}))];
                end
            end
            log_text = [log_text, sprintf('\n开始配准估计...\n')];
            app.textarea_log.Value{1} = log_text;

            % --- 4. 执行 Coregister ---
            try
                % 设置配准参数
                matlabbatch = {};
                matlabbatch{1}.spm.spatial.coreg.estwrite.ref = {reference_image};
                matlabbatch{1}.spm.spatial.coreg.estwrite.source = {source_image};
                % 处理Other Images
                other_images_str = app.edit_other_images.Value;
                if ~isempty(other_images_str)
                    other_images = strsplit(other_images_str, '; ');
                    other_images = other_images(~cellfun(@isempty, other_images));
                    for i = 1:length(other_images)
                        other_images{i} = strtrim(other_images{i});
                    end
                    matlabbatch{1}.spm.spatial.coreg.estwrite.other = other_images;
                end
                
                switch app.dropdown_obj_func.Value
                    case 'mutual'
                        cost_fun_val = 'mi';  % Mutual Information
                    case 'cc'
                        cost_fun_val = 'ncc'; % Normalized Cross Correlation
                    otherwise
                        cost_fun_val = 'nmi'; % Normalized Mutual Information (默认)
                end

                % 设置 Estimation Options
                matlabbatch{1}.spm.spatial.coreg.estwrite.eoptions.cost_fun = app.dropdown_obj_func.Value;
                matlabbatch{1}.spm.spatial.coreg.estwrite.eoptions.sep = [app.edit_separation1.Value, app.edit_separation2.Value];
                
                % 参数设置这里也不再读取界面，直接使用 default_tol
                matlabbatch{1}.spm.spatial.coreg.estwrite.eoptions.tol = default_tol;
                    
                matlabbatch{1}.spm.spatial.coreg.estwrite.eoptions.fwhm = [app.edit_hist_smooth1.Value, app.edit_hist_smooth2.Value];
                
                % 设置 Reslice Options
                interp_map = containers.Map(...
                    {'Nearest Neighbour', 'Trilinear', '2nd Degree B-Spline', '3rd Degree B-Spline', '4th Degree B-Spline'}, ...
                    [0, 1, 2, 3, 4]);
                matlabbatch{1}.spm.spatial.coreg.estwrite.roptions.interp = interp_map(app.dropdown_coreg_interp.Value);
                
                % 设置 Wrapping
                wrap_map = containers.Map({'No wrap', 'Wrap'}, [0, 1]);
                matlabbatch{1}.spm.spatial.coreg.estwrite.roptions.wrap = [wrap_map(app.dropdown_wrapping.Value) 0 0];
                
                % 设置 Masking
                mask_map = containers.Map({'Dont mask images', 'Mask images'}, [0, 1]);
                matlabbatch{1}.spm.spatial.coreg.estwrite.roptions.mask = mask_map(app.dropdown_masking.Value);
                matlabbatch{1}.spm.spatial.coreg.estwrite.roptions.prefix = app.edit_coreg_prefix.Value;
                
                % 运行配准
                spm_jobman('run', matlabbatch);
                
                [srcPathDir, srcNameExt, srcExt] = fileparts(source_image);
                srcName = srcNameExt(2:end);
                if strcmp(srcExt, '.gz')
                    [~, srcNameNoGz, ~] = fileparts(srcNameExt);
                    srcName = srcNameNoGz(2:end);
                end
                
                outputFile = fullfile(srcPathDir, [app.edit_coreg_prefix.Value srcName srcExt]);
                if exist('srcNameNoGz', 'var')
                    outputFile = fullfile(srcPathDir, [app.edit_coreg_prefix.Value srcNameNoGz(2:end) srcExt]);
                end
                
                log_text = [app.textarea_log.Value{1}, sprintf('\n=== 配准完成 ===\n\n')];
                log_text = [log_text, sprintf('Reference Image: %s\n', reference_image)];
                log_text = [log_text, sprintf('Source Image: %s\n', source_image)];
                log_text = [log_text, sprintf('输出文件: %s\n', outputFile)];
                log_text = [log_text, sprintf('配准类型: 刚体变换 (6自由度)\n')];
                log_text = [log_text, sprintf('目标函数: %s\n\n', app.dropdown_obj_func.Value)];
                log_text = [log_text, sprintf('提示: 输出文件以"%s"为前缀，表示已配准(registered)版本\n', app.edit_coreg_prefix.Value)];
                app.textarea_log.Value{1} = log_text;
            catch ME
                log_text = [app.textarea_log.Value{1}, sprintf('\n配准失败：%s\n', ME.message)];
                app.textarea_log.Value{1} = log_text;
                errordlg(['配准失败：' ME.message]);
            end
        end

        % Button pushed function: Button_6
        function Button_6Pushed2(app, event)
            [f,p] = uigetfile( ...
        {'*.nii;*.nii.gz','NIfTI files (*.nii, *.nii.gz)'}, ...
        'Select T1-weighted NIfTI');

    if isequal(f,0)
        app.t1Path = "";
        app.NotAvailableLabel.Text = "Not Available...";
        app.NotAvailableLabel.FontColor = [0.85 0 0];
        app.setStatus("T1w not imported");
        return;
    end

    app.t1Path = string(fullfile(p,f));
    app.NotAvailableLabel.Text = "Imported";
    app.NotAvailableLabel.FontColor = [0 0.6 0];
    app.setStatus("T1w NIfTI imported");
        end

        % Button pushed function: Button_7
        function Button_7Pushed2(app, event)
            [f,p] = uigetfile( ...
        {'*.nii;*.nii.gz','NIfTI files (*.nii, *.nii.gz)'}, ...
        'Select FLAIR NIfTI');

    if isequal(f,0)
        app.flairPath = "";
        app.NotAvailableLabel_2.Text = "Not Available...";
        app.NotAvailableLabel_2.FontColor = [0.85 0 0];
        app.setStatus("FLAIR not imported");
        return;
    end

    app.flairPath = string(fullfile(p,f));
    app.NotAvailableLabel_2.Text = "Imported";
    app.NotAvailableLabel_2.FontColor = [0 0.6 0];
    app.setStatus("FLAIR NIfTI imported");
        end

        % Button pushed function: Button_8
        function Button_8Pushed2(app, event)
              [f,p] = uigetfile( ...
        {'*.nii;*.nii.gz','NIfTI mask (*.nii, *.nii.gz)'}, ...
        'Select ROI Ground Truth (NIfTI)');

    if isequal(f,0)
        app.roiPath = "";
        app.NotAvailableLabel_3.Text = "Not Available...";
        app.NotAvailableLabel_3.FontColor = [0.85 0 0];
        app.setStatus("ROI not imported");
        return;
    end

    app.roiPath = string(fullfile(p,f));
    app.NotAvailableLabel_3.Text = "Imported";
    app.NotAvailableLabel_3.FontColor = [0 0.6 0];
    app.setStatus("ROI NIfTI imported");
        end

        % Button pushed function: Button_9
        function Button_9Pushed2(app, event)
             outDir = uigetdir(pwd, '选择保存输出的文件夹');
    if isequal(outDir, 0)
        app.saveBaseDir = "";

        % 红色提示：未选择
        app.NotAvailableLabel_4.Text = "Not Available...";
        app.NotAvailableLabel_4.FontColor = [0.85 0 0];

        app.setStatus("Save path not selected");
        return;
    end

    app.saveBaseDir = string(outDir);

    % 绿色提示：已选择
    app.NotAvailableLabel_4.Text = "Selected";
    app.NotAvailableLabel_4.FontColor = [0 0.6 0];

    app.setStatus("Save path selected: " + app.saveBaseDir);
        end

        % Button pushed function: StartSegmentationButton
        function StartSegmentationButtonPushed2(app, event)
            
% =======================
% Start Segmentation
% =======================

% ---- 1) 选择算法 ----
if app.SPM12TissueSegmentationT1onlyButton.Value
    algo = "SPM12_T1";
elseif app.LSTLesionPredictionFLAIRonlyButton.Value
    algo = "LST_LPA_FLAIR";
elseif app.LSTLesionGrowthT1FLAIRButton.Value
    algo = "LST_LGA_T1_FLAIR";
else
    uialert(app.UIFigure, "Please select an algorithm.", "No Selection");
    return;
end

% ---- 2) 输入检查 ----
needT1 = (algo=="SPM12_T1") || (algo=="LST_LGA_T1_FLAIR");
needFL = (algo=="LST_LPA_FLAIR") || (algo=="LST_LGA_T1_FLAIR");

if needT1 && strlength(app.t1Path)==0
    uialert(app.UIFigure, "T1w NIfTI is required.", "Missing Input");
    return;
end
if needFL && strlength(app.flairPath)==0
    uialert(app.UIFigure, "FLAIR NIfTI is required.", "Missing Input");
    return;
end

if needT1 && ~app.isNiftiPath(app.t1Path)
    uialert(app.UIFigure, "T1w must be *.nii or *.nii.gz.", "Invalid Format");
    return;
end
if needFL && ~app.isNiftiPath(app.flairPath)
    uialert(app.UIFigure, "FLAIR must be *.nii or *.nii.gz.", "Invalid Format");
    return;
end

% ---- 3) 输出目录（保留原逻辑）----
ts = datestr(now,'yyyymmdd_HHMMSS');
baseDir = app.saveBaseDir;
if strlength(baseDir)==0 || ~exist(char(baseDir),'dir')
    baseDir = string(tempdir);
end

app.segOutDir = string(fullfile(char(baseDir), "SegResult_" + algo + "_" + ts));
if ~exist(char(app.segOutDir),'dir')
    mkdir(char(app.segOutDir));
end

% reset result holders
app.segMaskPath = "";
app.segMaskVol  = [];

app.setStatus("Segmentation started: " + algo);

try
    switch algo

        % ===== SPM12 Tissue Segmentation (T1 only) =====
        case "SPM12_T1"
            app.ensureSPM12();
            tissuePaths = app.runSPM12_TissueSeg(app.t1Path, app.segOutDir);
            app.segTissuePaths = tissuePaths;

            uialert(app.UIFigure, ...
                "SPM12 finished." + newline + ...
                "c1~c6 tissue probability maps generated." + newline + ...
                "These are NOT lesion masks.", ...
                "SPM12 Output");

            % 预览白质 c2
            wmVol = niftiread(char(tissuePaths(2)));
            z = round(size(wmVol,3)/2);
            imagesc(app.ax_Processed, wmVol(:,:,z));
            colormap(app.ax_Processed, gray);
            axis(app.ax_Processed,'image');
            app.ax_Processed.Visible = 'off';
            title(app.ax_Processed, 'SPM12 WM (c2)');

            app.setStatus("SPM12 finished.");
            return;  % SPM12 不输出 lesion mask，这里直接结束

        % ===== LST LPA (FLAIR only) =====
        case "LST_LPA_FLAIR"
            app.ensureLST();

            app.setStatus("LST-LPA running...");
            % runLST_LPA 内部会把输入复制到 app.segOutDir，并在该目录产出结果
            app.segMaskPath = string(app.runLST_LPA(app.flairPath, app.segOutDir));

        % ===== LST LGA (T1 + FLAIR) =====
        case "LST_LGA_T1_FLAIR"
            app.ensureLST();

            app.setStatus("LST-LGA running...");
            % runLST_LGA 内部会：运行 + 记录输出 +（若设置 saveBaseDir）移动产物并更新 app.segOutDir/app.segMaskPath
            outMask = app.runLST_LGA(app.t1Path, app.flairPath);

            % 兼容：有的版本 runLST_LGA 已经把 app.segMaskPath 设好了
            if strlength(app.segMaskPath)==0
                app.segMaskPath = string(outMask);
            end

            % 兼容：runLST_LGA 可能会把 app.segOutDir 改为 LGA_时间戳目录
            % 若它已更新，则以它为准；否则保留本函数创建的 segOutDir
            if strlength(app.segOutDir)==0
                app.segOutDir = string(fileparts(char(app.segMaskPath)));
            end
    end

    % ---- 4) 读取并预览 mask（LPA/LGA 共用）----
    if strlength(app.segMaskPath)==0 || exist(char(app.segMaskPath),'file')~=2
        error("Segmentation mask not found. segMaskPath=%s", string(app.segMaskPath));
    end

    app.segMaskVol = (niftiread(char(app.segMaskPath)) > 0);

    % 预览一次
    app.previewMaskOnAxes(app.segMaskVol, app.ax_Processed, "Segmentation Preview");

    app.setStatus("Segmentation finished: " + algo);
    uialert(app.UIFigure, "Segmentation finished: " + algo, "Done");

catch ME
    disp(getReport(ME,'extended'));
    uialert(app.UIFigure, ME.message, "Segmentation Failed");
    app.setStatus("Segmentation failed: " + algo);
end
        end

        % Button pushed function: AccuracyValidationButton
        function AccuracyValidationButtonPushed2(app, event)
            
% =======================
% Accuracy Validation (Align pred->GT space + robust threshold)
% =======================

thrFixed = app.thrFixed0109EditField.Value;   % 0.1~0.9
if isempty(thrFixed) || ~isfinite(thrFixed)
    thrFixed = 0.5;
end

% ---- 1) 检查 GT ----
gtOk = (strlength(app.roiPath) > 0) && (exist(char(app.roiPath),'file') == 2);
if ~gtOk
    uialert(app.UIFigure, "ROI Ground Truth is required.", "Missing ROI");
    app.setStatus("Validation cancelled (missing GT).");
    return;
end

% ---- 2) 检查 Prediction ----
maskOk = (strlength(app.segMaskPath) > 0) && (exist(char(app.segMaskPath),'file') == 2);
if ~maskOk
    [f, pth] = uigetfile({'*.nii;*.nii.gz','NIfTI (*.nii, *.nii.gz)'}, ...
        'Select prediction output (mask/probability map)');
    if isequal(f,0)
        app.setStatus("Validation cancelled (no prediction selected).");
        return;
    end
    app.segMaskPath = string(fullfile(pth, f));
end

app.setStatus("Validation started.");

try
    % ---- 3.1) 读取 GT ----
    gtPath = char(app.roiPath);
    gtInfo = niftiinfo(gtPath);
    gtRaw  = niftiread(gtInfo);

    % GT 强制二值
    gt = (double(gtRaw) > 0);
    gt = logical(gt);

    % ---- 3.2) 读取 Prediction (RAW) ----
    predPath = char(app.segMaskPath);
    predInfo = niftiinfo(predPath);
    predRaw  = niftiread(predInfo);

    % 统一 double（配准/阈值稳）
    p = double(predRaw);

    % 文件名提示（LPA输出常为概率图）
    [~, fname, ~] = fileparts(predPath);
    isPleLpa = contains(lower(fname), "ples_lpa");

    % 判定是否“本来就是二值mask”
    u = unique(p(:));
    u = u(~isnan(u));
    isBinaryLike = (numel(u) <= 3) && all(ismember(u, [0 1]));

    % ====== Debug：RAW ======
    disp("==== PRED RAW DEBUG ====");
    disp("PredRaw size    = " + mat2str(size(p)));
    disp("PredRaw min/max = " + min(p(:)) + " / " + max(p(:)));
    disp("PredRaw nnz(>0) = " + nnz(p > 0));
    disp("GT size         = " + mat2str(size(gt)));
    disp("GT nnz          = " + nnz(gt));

    % ---- 3.3) 对齐 Prediction -> GT 空间（阈值前）----
    needAlign = ~isequal(size(p), size(gt));
    if isfield(predInfo,'PixelDimensions') && isfield(gtInfo,'PixelDimensions')
        pdm = double(predInfo.PixelDimensions(1:min(3,end)));
        pdf = double(gtInfo.PixelDimensions(1:min(3,end)));
        if numel(pdm)==3 && numel(pdf)==3
            needAlign = needAlign || any(abs(pdm - pdf) > 1e-6);
        end
    end

    if needAlign
        app.setStatus("Aligning prediction to GT space...");

        interp = "linear";
        if isBinaryLike
            interp = "nearest";
        end

        % !!! 关键：传的是体数据 p + 两个 info struct
        p = app.alignPredToGT(p, predInfo, gtInfo, interp);

        disp("==== AFTER ALIGN DEBUG ====");
        disp("Aligned p min/max = " + min(p(:)) + " / " + max(p(:)));
        disp("Aligned p nnz(>0) = " + nnz(p > 0));
    end

    % ---- 3.4) 阈值化（在 GT 空间上做）----
    mn = min(p(:)); mx = max(p(:));

    if isBinaryLike
        pred = (p > 0.5);
        thrInfo = "binary mask (after alignment)";
    else
        if (mn >= 0.0) && (mx <= 1.0)
            % thrFixed 太高 -> 预测全 0 的兜底
            if mx < thrFixed
                newThr = max(0.05, 0.5*mx);
                uialert(app.UIFigure, ...
                    sprintf("thrFixed=%.2f too high (max=%.3f). Auto set to %.3f.", thrFixed, mx, newThr), ...
                    "Threshold adjusted");
                thrFixed = newThr;
            end

            pred = (p >= thrFixed);
            if isPleLpa
                thrInfo = "ples_lpa prob map, thr=" + string(thrFixed);
            else
                thrInfo = "prob map [0,1], thr=" + string(thrFixed);
            end
        else
            % 非概率图：归一化后 Otsu
            p01 = (p - mn) / (mx - mn + eps);
            thr01 = graythresh(p01);
            pred = (p01 >= thr01);
            thrInfo = "non-prob map, Otsu thr01=" + string(thr01);
        end
    end

    pred = logical(pred);  % 强制 logical，避免类型乱

    % ====== Debug：BIN ======
    disp("==== PRED BIN DEBUG ====");
    disp("thrInfo   = " + thrInfo);
    disp("Pred nnz  = " + nnz(pred));
    disp("Overlap   = " + nnz(pred & gt));

    % 如果还是全 0，直接提示（一般是对齐跑飞/Transform 缺失）
    if nnz(pred) == 0 && nnz(p > 0) > 0
        uialert(app.UIFigure, ...
            "Prediction becomes all-zero AFTER threshold in GT space. " + newline + ...
            "Likely原因：对齐后强度被稀释/阈值过高/Transform信息异常。请查看控制台 DEBUG。", ...
            "All-zero prediction");
    end

    % ---- 3.5) 指标计算 ----
    [dic, iou, precision, recall, f1] = app.calcSegMetrics(pred, gt);

    % ---- 4) 预览 ----
    app.previewMaskOnAxes(pred, app.ax_Processed, "Prediction (" + thrInfo + ")");

    % ---- 5) UI表格 ----
    dic_good = 0.70; dic_fair = 0.40;
    iou_good = 0.50; iou_fair = 0.25;
    pr_good  = 0.70; pr_fair  = 0.40;
    re_good  = 0.70; re_fair  = 0.40;
    f1_good  = 0.70; f1_fair  = 0.40;

    app.UITable2.Data = {
        'Dice',      double(dic),       app.grade3(dic,      dic_good, dic_fair);
        'IoU',       double(iou),       app.grade3(iou,      iou_good, iou_fair);
        'Precision', double(precision), app.grade3(precision,pr_good,  pr_fair);
        'Recall',    double(recall),    app.grade3(recall,   re_good,  re_fair);
        'F1',        double(f1),        app.grade3(f1,       f1_good,  f1_fair);
    };

    app.setStatus("Validation finished. (" + thrInfo + ")");

catch ME
    disp(getReport(ME, 'extended'));
    uialert(app.UIFigure, ME.message, "Validation Failed");
    app.setStatus("Validation failed.");
end
        end

        % Button pushed function: btn_browse_spm
        function btn_browse_spmButtonPushed2(app, event)
              path = uigetdir('', '选择SPM12安装文件夹');
            if path ~= 0
                app.edit_spm.Value = path;
                app.textarea_log.Value{1} = sprintf('已选择SPM12路径: %s\n', path);
            end
        end

        % Button pushed function: btn_browse_images_to_write
        function btn_browse_images_to_writeButtonPushed2(app, event)
             [write_files, write_path] = uigetfile( ...
                '*.nii', ...
                '选择 Images to Write', ...
                'MultiSelect', 'on');
            if write_files ~= 0
                if iscell(write_files)
                    full_paths = cell(1, length(write_files));
                    for i = 1:length(write_files)
                        full_paths{i} = fullfile(write_path, write_files{i});
                    end
                    app.edit_images_to_write.Value = sprintf('%s; ', full_paths{:});
                else
                    full_path = fullfile(write_path, write_files);
                    app.edit_images_to_write.Value = full_path;
                end
                app.textarea_log.Value{1} = sprintf('已选择 Images to Write: %s\n', app.edit_images_to_write.Value);
            end
        end

        % Button pushed function: btn_browse_image_to_align
        function btn_browse_image_to_alignButtonPushed2(app, event)
             [file, path] = uigetfile('*.nii', '选择 Image to Align');
            if file ~= 0
                full_path = fullfile(path, file);
                app.edit_image_to_align.Value = full_path;
                app.textarea_log.Value{1} = sprintf('已选择 Image to Align: %s\n', full_path);
            end
        end

        % Button pushed function: btn_browse_tissue_prob_map
        function btn_browse_tissue_prob_mapButtonPushed2(app, event)
            [file, path] = uigetfile('TPM.nii', '选择 Tissue probability map');
            if file ~= 0
                full_path = fullfile(path, file);
                app.edit_tissue_prob_map.Value = full_path;
                app.textarea_log.Value{1} = sprintf('已选择 Tissue probability map: %s\n', full_path);
            end

        end

        % Button pushed function: btn_run_normalize
        function btn_run_normalizeButtonPushed2(app, event)
            
            spm_path = app.edit_spm.Value;
            image_to_align = app.edit_image_to_align.Value;
            images_to_write_str = app.edit_images_to_write.Value;
            tissue_prob_map = app.edit_tissue_prob_map.Value;

            % --- 1. 基础检查 ---
            if isempty(spm_path)
                if exist('spm', 'file') == 2
                    spm_path = fileparts(which('spm'));
                    app.edit_spm.Value = spm_path;
                    app.textarea_log.Value{1} = sprintf('自动检测到SPM12路径: %s\n', spm_path);
                else
                    errordlg('请先选择SPM12路径！');
                    return;
                end
            end
            if isempty(image_to_align)
                errordlg('请先选择 Image to Align！');
                return;
            end
            if isempty(images_to_write_str)
                errordlg('请先选择 Images to Write！');
                return;
            end
            if isempty(tissue_prob_map)
                errordlg('请先选择 Tissue probability map！');
                return;
            end

            addpath(spm_path);
            spm('defaults', 'FMRI');
            spm_jobman('initcfg');

            % --- 2. 处理文件列表 ---
            images_to_write = strsplit(images_to_write_str, '; ');
            images_to_write = images_to_write(~cellfun(@isempty, images_to_write));
            for i = 1:length(images_to_write)
                images_to_write{i} = strtrim(images_to_write{i});
            end

            % --- 3. 设置默认参数  ---
            % 默认 Warping Regularisation [0 0.001 0.5 0.05 0.2]
            default_warp_reg = [0, 0.001, 0.5, 0.05, 0.2]; 
            
            % 默认 Bounding box [-90 -126 -72; 90 90 106]
            default_bb = [-90 -126 -72; 90 90 106]; 

            % --- 4. 生成日志 ---
            log_text = sprintf('========================================\n');
            log_text = [log_text, sprintf('   SPM12 Normalize (Estimate & Write)\n')];
            log_text = [log_text, sprintf('========================================\n')];
            log_text = [log_text, sprintf('SPM12路径: %s\n', spm_path)];
            log_text = [log_text, sprintf('Image to Align: %s\n', image_to_align)];
            log_text = [log_text, sprintf('Images to Write: %d 个\n', length(images_to_write))];
            for i = 1:length(images_to_write)
                log_text = [log_text, sprintf('  %d. %s\n', i, images_to_write{i})];
            end
            log_text = [log_text, sprintf('Tissue probability map: %s\n', tissue_prob_map)];
            log_text = [log_text, sprintf('\n参数设置:\n')];
            log_text = [log_text, sprintf('  Bias regularisation: %.6f\n', app.edit_bias_reg.Value)];
            log_text = [log_text, sprintf('  Bias FWHM: %.1f mm\n', app.edit_bias_fwhm.Value)];
            log_text = [log_text, sprintf('  Affine Regularisation: %s\n', app.dropdown_affine_reg.Value)];
            
            % 日志显示默认值
            log_text = [log_text, sprintf('  Warping Regularisation: [%.4f, %.4f, %.4f, %.4f, %.4f] (默认)\n', ...
                default_warp_reg)];
            
            log_text = [log_text, sprintf('  Smoothness: %.1f\n', app.edit_smoothness.Value)];
            log_text = [log_text, sprintf('  Sampling distance: %.1f\n', app.edit_sampling_dist.Value)];
            log_text = [log_text, sprintf('  Voxel sizes: [%.1f, %.1f, %.1f]\n', ...
                app.edit_voxel_size1.Value, app.edit_voxel_size2.Value, app.edit_voxel_size3.Value)];


            app.textarea_log.Value{1} = log_text;

            % --- 5. 执行 SPM ---
            try
                job = [];
                job.subj(1).vol = {image_to_align};
                job.subj(1).resample = images_to_write;
                
                % 设置 Estimation Options
                job.eoptions.biasreg = app.edit_bias_reg.Value;
                job.eoptions.biasfwhm = app.edit_bias_fwhm.Value;
                job.eoptions.tpm = {tissue_prob_map};
                job.eoptions.affreg = app.dropdown_affine_reg.Value;
                
                % 使用变量 default_warp_reg
                job.eoptions.reg = default_warp_reg;
                
                job.eoptions.fwhm = app.edit_smoothness.Value;
                job.eoptions.samp = app.edit_sampling_dist.Value;
                
                % 设置 Writing Options
                % 使用变量 default_bb...
                job.woptions.bb = default_bb;
                
                job.woptions.vox = [app.edit_voxel_size1.Value, app.edit_voxel_size2.Value, app.edit_voxel_size3.Value];
                
                % 设置插值方法
                interp_map = containers.Map(...
                    {'Nearest Neighbour', 'Trilinear', '2nd Degree B-Spline', '3rd Degree B-Spline', '4th Degree B-Spline'}, ...
                    [0, 1, 2, 3, 4]);
                job.woptions.interp = interp_map(app.dropdown_interp.Value);
                job.woptions.prefix = app.edit_prefix.Value;
                
                spm_run_norm(job);
                
                log_text = [app.textarea_log.Value{1}, sprintf('\n配准完成！\n')];
                app.textarea_log.Value{1} = log_text;
            catch ME
                log_text = [app.textarea_log.Value{1}, sprintf('\n配准失败：%s\n', ME.message)];
                app.textarea_log.Value{1} = log_text;
                errordlg(['配准失败：' ME.message]);
            end
        end

        % Button pushed function: btn_browse_spm_2
        function btn_browse_spm_2ButtonPushed2(app, event)
               path = uigetdir('', '选择SPM12安装文件夹');
            if path ~= 0
                app.edit_spm_2.Value = path;
                app.textarea_log.Value{1} = sprintf('已选择SPM12路径: %s\n', path);
            end
        end

        % Button pushed function: btn_browse_reference_image
        function btn_browse_reference_imageButtonPushed2(app, event)
                 [file, path] = uigetfile( ...
                {'*.nii;*.nii.gz', 'NIfTI 文件 (*.nii, *.nii.gz)'; ...
                '*.*', '所有文件 (*.*)'}, ...
                '选择 Reference Image');
            if file ~= 0
                full_path = fullfile(path, file);
                app.edit_reference_image.Value = full_path;
                app.textarea_log.Value{1} = sprintf('已选择 Reference Image: %s\n', full_path);
            end
        end

        % Button pushed function: btn_browse_source_image
        function btn_browse_source_imageButtonPushed2(app, event)
                [file, path] = uigetfile( ...
                {'*.nii;*.nii.gz', 'NIfTI 文件 (*.nii, *.nii.gz)'; ...
                '*.*', '所有文件 (*.*)'}, ...
                '选择 Source Image');
            if file ~= 0
                full_path = fullfile(path, file);
                app.edit_source_image.Value = full_path;
                app.textarea_log.Value{1} = sprintf('已选择 Source Image: %s\n', full_path);
            end
        end

        % Button pushed function: btn_run_coregister
        function btn_run_coregisterButtonPushed2(app, event)
            
            spm_path = app.edit_spm_2.Value;
            reference_image = app.edit_reference_image.Value;
            source_image = app.edit_source_image.Value;

            % --- 1. 基础检查 ---
            if isempty(spm_path)
                if exist('spm', 'file') == 2
                    spm_path = fileparts(which('spm'));
                    app.edit_spm_2.Value = spm_path;
                    app.textarea_log.Value{1} = sprintf('自动检测到SPM12路径: %s\n', spm_path);
                else
                    errordlg('请先选择SPM12路径！');
                    return;
                end
            end
            if isempty(reference_image)
                errordlg('请先选择 Reference Image！');
                return;
            end
            if isempty(source_image)
                errordlg('请先选择 Source Image！');
                return;
            end

            addpath(spm_path);
            spm('defaults', 'FMRI');
            spm_jobman('initcfg');

            % --- 2. 定义默认 Tolerances  ---
            default_tol = [0.02, 0.02, 0.02, 0.001, 0.001, 0.001, 0.01, 0.01, 0.01, 0.001, 0.001, 0.001];

            % --- 3. 生成日志 ---
            log_text = sprintf('========================================\n');
            log_text = [log_text, sprintf('   SPM12 Coregister (Estimate & Write)\n')];
            log_text = [log_text, sprintf('========================================\n')];
            log_text = [log_text, sprintf('SPM12路径: %s\n', spm_path)];
            log_text = [log_text, sprintf('Reference Image: %s\n', reference_image)];
            log_text = [log_text, sprintf('Source Image: %s\n', source_image)];
            log_text = [log_text, sprintf('\n参数设置:\n')];
            log_text = [log_text, sprintf('  Objective Function: %s\n', app.dropdown_obj_func.Value)];
            log_text = [log_text, sprintf('  Separation: [%.1f, %.1f]\n', app.edit_separation1.Value, app.edit_separation2.Value)];
            
            % 日志这里不再从界面读取，而是直接显示 default_tol 变量
            log_text = [log_text, sprintf('  Tolerances: [%.3f %.3f %.3f %.4f %.4f %.4f %.3f %.3f %.3f %.4f %.4f %.4f] (默认)\n', ...
                default_tol)];
                
            log_text = [log_text, sprintf('  Histogram Smoothing: [%.1f, %.1f]\n', app.edit_hist_smooth1.Value, app.edit_hist_smooth2.Value)];
            log_text = [log_text, sprintf('  Interpolation: %s\n', app.dropdown_coreg_interp.Value)];
            log_text = [log_text, sprintf('  Wrapping: %s\n', app.dropdown_wrapping.Value)];
            log_text = [log_text, sprintf('  Masking: %s\n', app.dropdown_masking.Value)];
            log_text = [log_text, sprintf('  Filename Prefix: %s\n', app.edit_coreg_prefix.Value)];
            
            % 处理Other Images日志
            other_images_str = app.edit_other_images.Value;
            if ~isempty(other_images_str)
                other_images = strsplit(other_images_str, '; ');
                other_images = other_images(~cellfun(@isempty, other_images));
                log_text = [log_text, sprintf('  Other Images: %d 个\n', length(other_images))];
                for i = 1:length(other_images)
                    log_text = [log_text, sprintf('    %d. %s\n', i, strtrim(other_images{i}))];
                end
            end
          
            app.textarea_log.Value{1} = log_text;

            % --- 4. 执行 Coregister ---
            try
                % 设置配准参数
                matlabbatch = {};
                matlabbatch{1}.spm.spatial.coreg.estwrite.ref = {reference_image};
                matlabbatch{1}.spm.spatial.coreg.estwrite.source = {source_image};
                % 处理Other Images
                other_images_str = app.edit_other_images.Value;
                if ~isempty(other_images_str)
                    other_images = strsplit(other_images_str, '; ');
                    other_images = other_images(~cellfun(@isempty, other_images));
                    for i = 1:length(other_images)
                        other_images{i} = strtrim(other_images{i});
                    end
                    matlabbatch{1}.spm.spatial.coreg.estwrite.other = other_images;
                end
                
                switch app.dropdown_obj_func.Value
                    case 'mutual'
                        cost_fun_val = 'mi';  % Mutual Information
                    case 'cc'
                        cost_fun_val = 'ncc'; % Normalized Cross Correlation
                    otherwise
                        cost_fun_val = 'nmi'; % Normalized Mutual Information (默认)
                end

                % 设置 Estimation Options
                matlabbatch{1}.spm.spatial.coreg.estwrite.eoptions.cost_fun = app.dropdown_obj_func.Value;
                matlabbatch{1}.spm.spatial.coreg.estwrite.eoptions.sep = [app.edit_separation1.Value, app.edit_separation2.Value];
                
                % 参数设置这里也不再读取界面，直接使用 default_tol
                matlabbatch{1}.spm.spatial.coreg.estwrite.eoptions.tol = default_tol;
                    
                matlabbatch{1}.spm.spatial.coreg.estwrite.eoptions.fwhm = [app.edit_hist_smooth1.Value, app.edit_hist_smooth2.Value];
                
                % 设置 Reslice Options
                interp_map = containers.Map(...
                    {'Nearest Neighbour', 'Trilinear', '2nd Degree B-Spline', '3rd Degree B-Spline', '4th Degree B-Spline'}, ...
                    [0, 1, 2, 3, 4]);
                matlabbatch{1}.spm.spatial.coreg.estwrite.roptions.interp = interp_map(app.dropdown_coreg_interp.Value);
                
                % 设置 Wrapping
                wrap_map = containers.Map({'No wrap', 'Wrap'}, [0, 1]);
                matlabbatch{1}.spm.spatial.coreg.estwrite.roptions.wrap = [wrap_map(app.dropdown_wrapping.Value) 0 0];
                
                % 设置 Masking
                mask_map = containers.Map({'Dont mask images', 'Mask images'}, [0, 1]);
                matlabbatch{1}.spm.spatial.coreg.estwrite.roptions.mask = mask_map(app.dropdown_masking.Value);
                matlabbatch{1}.spm.spatial.coreg.estwrite.roptions.prefix = app.edit_coreg_prefix.Value;
                
                % 运行配准
                spm_jobman('run', matlabbatch);
                
                [srcPathDir, srcNameExt, srcExt] = fileparts(source_image);
                srcName = srcNameExt(2:end);
                if strcmp(srcExt, '.gz')
                    [~, srcNameNoGz, ~] = fileparts(srcNameExt);
                    srcName = srcNameNoGz(2:end);
                end
                
                outputFile = fullfile(srcPathDir, [app.edit_coreg_prefix.Value srcName srcExt]);
                if exist('srcNameNoGz', 'var')
                    outputFile = fullfile(srcPathDir, [app.edit_coreg_prefix.Value srcNameNoGz(2:end) srcExt]);
                end
                
                log_text = [app.textarea_log.Value{1}, sprintf('\n=== 配准完成 ===\n\n')];
                log_text = [log_text, sprintf('Reference Image: %s\n', reference_image)];
                log_text = [log_text, sprintf('Source Image: %s\n', source_image)];
                log_text = [log_text, sprintf('输出文件: %s\n', outputFile)];
                log_text = [log_text, sprintf('配准类型: 刚体变换 (6自由度)\n')];
                log_text = [log_text, sprintf('目标函数: %s\n\n', app.dropdown_obj_func.Value)];
                log_text = [log_text, sprintf('提示: 输出文件以"%s"为前缀，表示已配准(registered)版本\n', app.edit_coreg_prefix.Value)];
                app.textarea_log.Value{1} = log_text;
            catch ME
                log_text = [app.textarea_log.Value{1}, sprintf('\n配准失败：%s\n', ME.message)];
                app.textarea_log.Value{1} = log_text;
                errordlg(['配准失败：' ME.message]);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = '生物医学影像分析';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = '主界面';
            app.ax_Histogram = uiaxes(app.Tab);
            title(app.ax_Histogram, '灰度直方图')
            xlabel(app.ax_Histogram, 'X')
            ylabel(app.ax_Histogram, 'Y')
            zlabel(app.ax_Histogram, 'Z')
            app.Panel_Control = uipanel(app.Tab);
            app.Panel_Control.Title = '功能面板';
            app.btn_ImportDICOM = uibutton(app.Panel_Control, 'push');
            app.btn_ImportDICOM.ButtonPushedFcn = createCallbackFcn(app, @btn_ImportDICOMPushed, true);
            app.btn_ImportDICOM.Text = '导入DICOM ';
            app.btn_GrayConvert = uibutton(app.Panel_Control, 'push');
            app.btn_GrayConvert.ButtonPushedFcn = createCallbackFcn(app, @btn_GrayConvertPushed, true);
            app.btn_GrayConvert.Text = '灰度转换';
            app.btn_Reset = uibutton(app.Panel_Control, 'push');
            app.btn_Reset.ButtonPushedFcn = createCallbackFcn(app, @btn_ResetPushed, true);
            app.btn_Reset.Text = '图像还原';
            app.btn_ImportStandard = uibutton(app.Panel_Control, 'push');
            app.btn_ImportStandard.ButtonPushedFcn = createCallbackFcn(app, @btn_ImportStandardButtonPushed, true);
            app.btn_ImportStandard.Text = '导入自然图像';
            app.btn_HistEq = uibutton(app.Panel_Control, 'push');
            app.btn_HistEq.ButtonPushedFcn = createCallbackFcn(app, @btn_HistEqButtonPushed, true);
            app.btn_HistEq.Text = '直方图均衡化';
            app.btn_CLAHE = uibutton(app.Panel_Control, 'push');
            app.btn_CLAHE.ButtonPushedFcn = createCallbackFcn(app, @btn_CLAHEButtonPushed, true);
            app.btn_CLAHE.Text = '自适应均衡化';
            app.btn_Save = uibutton(app.Panel_Control, 'push');
            app.btn_Save.ButtonPushedFcn = createCallbackFcn(app, @btn_SaveButtonPushed, true);
            app.btn_Save.Text = '保存结果';
            app.DICOMButton = uibutton(app.Panel_Control, 'push');
            app.DICOMButton.ButtonPushedFcn = createCallbackFcn(app, @DICOMButtonPushed, true);
            app.DICOMButton.Text = '自然图像转DICOM';
            app.Button = uibutton(app.Panel_Control, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '显示作者信息';
            app.NIfTIButton = uibutton(app.Panel_Control, 'push');
            app.NIfTIButton.ButtonPushedFcn = createCallbackFcn(app, @NIfTIButtonPushed, true);
            app.NIfTIButton.Text = '导入NIfTI ';
            app.DICOMNIfTIButton = uibutton(app.Panel_Control, 'push');
            app.DICOMNIfTIButton.ButtonPushedFcn = createCallbackFcn(app, @DICOMNIfTIButtonPushed, true);
            app.DICOMNIfTIButton.Text = 'DICOM转NIfTI';
            app.NIfTIDICOMButton = uibutton(app.Panel_Control, 'push');
            app.NIfTIDICOMButton.ButtonPushedFcn = createCallbackFcn(app, @NIfTIDICOMButtonPushed, true);
            app.NIfTIDICOMButton.Text = 'NIfTI转DICOM';
            app.Button_2 = uibutton(app.Panel_Control, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '显示直方图';
            app.Panel_Display = uipanel(app.Tab);
            app.Panel_Display.Title = '文件显示区';
            app.ax_Processed = uiaxes(app.Panel_Display);
            app.ax_Processed.XTick = [];
            app.ax_Processed.YTick = [];
            app.ax_Original = uiaxes(app.Panel_Display);
            app.ax_Original.XTick = [];
            app.ax_Original.YTick = [];
            app.lbl_OriginalTitle = uilabel(app.Panel_Display);
            app.lbl_OriginalTitle.Text = 'Original';
            app.lbl_ProcessedTitle = uilabel(app.Panel_Display);
            app.lbl_ProcessedTitle.Text = 'Processed';
            app.Panel = uipanel(app.Tab);
            app.Panel.Title = '参数调节';
            app.slider_Brightness = uislider(app.Panel);
            app.slider_Brightness.Limits = [-100 100];
            app.slider_Brightness.ValueChangedFcn = createCallbackFcn(app, @slider_BrightnessValueChanged, true);
            app.lbl_SliceInfo = uilabel(app.Panel);
            app.lbl_SliceInfo.Text = '切片层数';
            app.Label_2 = uilabel(app.Panel);
            app.Label_2.Text = '边缘检测算法';
            app.dd_EdgeAlgorithm = uidropdown(app.Panel);
            app.dd_EdgeAlgorithm.Items = {'None', 'Sobel', 'Prewitt', 'Canny'};
            app.dd_EdgeAlgorithm.ValueChangedFcn = createCallbackFcn(app, @dd_EdgeAlgorithmValueChanged, true);
            app.dd_EdgeAlgorithm.Value = 'Sobel';
            app.Label_3 = uilabel(app.Panel);
            app.Label_3.Text = '亮度调节';
            app.slider_Slice = uislider(app.Panel);
            app.slider_Slice.Limits = [-100 100];
            app.slider_Slice.ValueChangedFcn = createCallbackFcn(app, @slider_SliceValueChanged, true);
            app.Label_4 = uilabel(app.Panel);
            app.Label_4.Text = '对比度调节';
            app.slider_Contrast = uislider(app.Panel);
            app.slider_Contrast.Limits = [0.5 2];
            app.slider_Contrast.ValueChangedFcn = createCallbackFcn(app, @slider_ContrastValueChanged, true);
            app.slider_Contrast.Value = 1;
            app.SliderLabel = uilabel(app.Panel);
            app.SliderLabel.Text = '放大缩小';
            app.Slider = uislider(app.Panel);
            app.Slider.Limits = [1 6];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.Value = 1;
            app.lbl_Status = uilabel(app.Tab);
            app.lbl_Status.Text = '状态：等待操作...';
            app.Panel_2 = uipanel(app.Tab);
            app.Panel_2.Title = '高亮白质体素大小';
            app.Button_3 = uibutton(app.Panel_2, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '计算体素大小';
            app.Button_4 = uibutton(app.Panel_2, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '保存';
            app.UITable = uitable(app.Panel_2);
            app.UITable.ColumnName = {'ROI'; 'Volume(mm^3)'; 'x(mm)'; 'y(mm)'; 'z(mm)'; 'ClusterID'};
            app.UITable.RowName = {};
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection2, true);
            app.UITable.ClickedFcn = createCallbackFcn(app, @UITableClicked, true);
            app.Button_5 = uibutton(app.Panel_2, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Text = '聚类分析';
            app.MedicalImageSegmentationPanel = uipanel(app.Tab);
            app.MedicalImageSegmentationPanel.Title = 'Medical Image Segmentation';
            app.GridLayout = uigridlayout(app.MedicalImageSegmentationPanel);
            app.DataLoadingPanel = uipanel(app.GridLayout);
            app.DataLoadingPanel.Title = 'Data Loading';
            app.Button_6 = uibutton(app.DataLoadingPanel, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed2, true);
            app.Button_6.Text = '浏览 ....';
            app.Button_6.Icon = fullfile(pathToMLAPP, 'tp', '139048697_p0.jpg');
            app.T1wLabel = uilabel(app.DataLoadingPanel);
            app.T1wLabel.Text = 'T1w 结构像：';
            app.FLAIRLabel = uilabel(app.DataLoadingPanel);
            app.FLAIRLabel.Text = 'FLAIR 序列：';
            app.GTLabel = uilabel(app.DataLoadingPanel);
            app.GTLabel.Text = 'GT：';
            app.Button_7 = uibutton(app.DataLoadingPanel, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @Button_7Pushed2, true);
            app.Button_7.Text = '浏览 ....';
            app.Button_7.Icon = fullfile(pathToMLAPP, 'tp', '139047523_p0.png');
            app.Button_8 = uibutton(app.DataLoadingPanel, 'push');
            app.Button_8.ButtonPushedFcn = createCallbackFcn(app, @Button_8Pushed2, true);
            app.Button_8.Text = '浏览 ....';
            app.Button_8.Icon = fullfile(pathToMLAPP, 'tp', '139032427_p0.jpg');
            app.NotAvailableLabel = uilabel(app.DataLoadingPanel);
            app.NotAvailableLabel.Text = 'Not Available...';
            app.NotAvailableLabel_2 = uilabel(app.DataLoadingPanel);
            app.NotAvailableLabel_2.Text = 'Not Available...';
            app.NotAvailableLabel_3 = uilabel(app.DataLoadingPanel);
            app.NotAvailableLabel_3.Text = 'Not Available...';
            app.Label_5 = uilabel(app.DataLoadingPanel);
            app.Label_5.Text = '保存路径：';
            app.Button_9 = uibutton(app.DataLoadingPanel, 'push');
            app.Button_9.ButtonPushedFcn = createCallbackFcn(app, @Button_9Pushed2, true);
            app.Button_9.Text = '浏览 ....';
            app.Button_9.Icon = fullfile(pathToMLAPP, 'tp', '138884316_p0.jpg');
            app.NotAvailableLabel_4 = uilabel(app.DataLoadingPanel);
            app.NotAvailableLabel_4.Text = 'Not Available...';
            app.AlgorithmConfigurationButtonGroup = uibuttongroup(app.GridLayout);
            app.AlgorithmConfigurationButtonGroup.Title = 'Algorithm Configuration';
            app.SPM12TissueSegmentationT1onlyButton = uiradiobutton(app.AlgorithmConfigurationButtonGroup);
            app.SPM12TissueSegmentationT1onlyButton.Text = 'SPM12: Tissue Segmentation (T1 only)';
            app.SPM12TissueSegmentationT1onlyButton.Value = true;
            app.LSTLesionPredictionFLAIRonlyButton = uiradiobutton(app.AlgorithmConfigurationButtonGroup);
            app.LSTLesionPredictionFLAIRonlyButton.Text = 'LST: Lesion Prediction (FLAIR only)';
            app.LSTLesionGrowthT1FLAIRButton = uiradiobutton(app.AlgorithmConfigurationButtonGroup);
            app.LSTLesionGrowthT1FLAIRButton.Text = 'LST: Lesion Growth (T1 + FLAIR)';
            app.StartSegmentationButton = uibutton(app.AlgorithmConfigurationButtonGroup, 'push');
            app.StartSegmentationButton.ButtonPushedFcn = createCallbackFcn(app, @StartSegmentationButtonPushed2, true);
            app.StartSegmentationButton.Text = 'Start Segmentation';
            app.AccuracyValidationButton = uibutton(app.AlgorithmConfigurationButtonGroup, 'push');
            app.AccuracyValidationButton.ButtonPushedFcn = createCallbackFcn(app, @AccuracyValidationButtonPushed2, true);
            app.AccuracyValidationButton.Text = 'Accuracy Validation';
            app.EvaluationMetricsPanel = uipanel(app.GridLayout);
            app.EvaluationMetricsPanel.Title = 'Evaluation Metrics';
            app.UITable2 = uitable(app.EvaluationMetricsPanel);
            app.UITable2.ColumnName = {'Metric'; 'Value'; 'Status'};
            app.UITable2.RowName = {};
            app.ParameterSettingsPanel = uipanel(app.GridLayout);
            app.ParameterSettingsPanel.Title = 'Parameter Settings';
            app.kappa0108EditFieldLabel = uilabel(app.ParameterSettingsPanel);
            app.kappa0108EditFieldLabel.Text = 'kappa(0.1 ~ 0.8)：';
            app.kappa0108EditField = uieditfield(app.ParameterSettingsPanel, 'numeric');
            app.kappa0108EditField.Value = 0.3;
            app.maxiter10200EditFieldLabel = uilabel(app.ParameterSettingsPanel);
            app.maxiter10200EditFieldLabel.Text = 'maxiter(10 ~ 200)：';
            app.maxiter10200EditField = uieditfield(app.ParameterSettingsPanel, 'numeric');
            app.maxiter10200EditField.Value = 50;
            app.biasfwhm30120EditFieldLabel = uilabel(app.ParameterSettingsPanel);
            app.biasfwhm30120EditFieldLabel.Text = 'biasfwhm(30 ~ 120)：';
            app.biasfwhm30120EditField = uieditfield(app.ParameterSettingsPanel, 'numeric');
            app.biasfwhm30120EditField.Value = 60;
            app.biasreg00001001EditFieldLabel = uilabel(app.ParameterSettingsPanel);
            app.biasreg00001001EditFieldLabel.Text = 'biasreg(0.0001 ~ 0.01)：';
            app.biasreg00001001EditField = uieditfield(app.ParameterSettingsPanel, 'numeric');
            app.biasreg00001001EditField.Value = 0.001;
            app.thrFixed0109EditFieldLabel = uilabel(app.ParameterSettingsPanel);
            app.thrFixed0109EditFieldLabel.Text = 'thrFixed(0.1 ~ 0.9)：';
            app.thrFixed0109EditField = uieditfield(app.ParameterSettingsPanel, 'numeric');
            app.Label = uilabel(app.Tab);
            app.Label.Text = '相关信息显示';
            app.txt_Stats = uitextarea(app.Tab);
            app.txt_Stats.ValueChangedFcn = createCallbackFcn(app, @txt_StatsValueChanged, true);
            app.Image = uiimage(app.Tab);
            app.Image.ImageSource = fullfile(pathToMLAPP, '139032427_p0.jpg');
            app.Tab_2 = uitab(app.TabGroup);
            app.Tab_2.Title = '配准界面';
            app.CoregEstimationPanel = uipanel(app.Tab_2);
            app.CoregEstimationPanel.Title = 'NormalizeTab';
            app.btn_browse_spm = uibutton(app.CoregEstimationPanel, 'push');
            app.btn_browse_spm.ButtonPushedFcn = createCallbackFcn(app, @btn_browse_spmButtonPushed2, true);
            app.btn_browse_spm.Text = '浏览..';
            app.btn_browse_image_to_align = uibutton(app.CoregEstimationPanel, 'push');
            app.btn_browse_image_to_align.ButtonPushedFcn = createCallbackFcn(app, @btn_browse_image_to_alignButtonPushed2, true);
            app.btn_browse_image_to_align.Text = '浏览...';
            app.btn_browse_images_to_write = uibutton(app.CoregEstimationPanel, 'push');
            app.btn_browse_images_to_write.ButtonPushedFcn = createCallbackFcn(app, @btn_browse_images_to_writeButtonPushed2, true);
            app.btn_browse_images_to_write.Text = '浏览...';
            app.btn_browse_tissue_prob_map = uibutton(app.CoregEstimationPanel, 'push');
            app.btn_browse_tissue_prob_map.ButtonPushedFcn = createCallbackFcn(app, @btn_browse_tissue_prob_mapButtonPushed2, true);
            app.btn_browse_tissue_prob_map.Text = '浏览...';
            app.btn_run_normalize = uibutton(app.CoregEstimationPanel, 'push');
            app.btn_run_normalize.ButtonPushedFcn = createCallbackFcn(app, @btn_run_normalizeButtonPushed2, true);
            app.btn_run_normalize.Text = 'Normalize';
            app.edit_voxel_size2 = uieditfield(app.CoregEstimationPanel, 'numeric');
            app.edit_voxel_size2.Value = 2;
            app.edit_voxel_size3 = uieditfield(app.CoregEstimationPanel, 'numeric');
            app.edit_voxel_size3.Value = 2;
            app.SPM12Label = uilabel(app.CoregEstimationPanel);
            app.SPM12Label.Text = 'SPM12';
            app.edit_spm = uieditfield(app.CoregEstimationPanel, 'text');
            app.Label_6 = uilabel(app.CoregEstimationPanel);
            app.Label_6.Text = 'Images to Write';
            app.edit_images_to_write = uieditfield(app.CoregEstimationPanel, 'text');
            app.Label_7 = uilabel(app.CoregEstimationPanel);
            app.Label_7.Text = 'Image to Align';
            app.edit_image_to_align = uieditfield(app.CoregEstimationPanel, 'text');
            app.Label_8 = uilabel(app.CoregEstimationPanel);
            app.Label_8.Text = 'Tissue probability map';
            app.edit_tissue_prob_map = uieditfield(app.CoregEstimationPanel, 'text');
            app.Label_9 = uilabel(app.CoregEstimationPanel);
            app.Label_9.Text = '仿射正则化';
            app.dropdown_affine_reg = uidropdown(app.CoregEstimationPanel);
            app.dropdown_affine_reg.Items = {'mni', 'none'};
            app.dropdown_affine_reg.Value = 'mni';
            app.Label_10 = uilabel(app.CoregEstimationPanel);
            app.Label_10.Text = '偏差正则化';
            app.edit_bias_reg = uieditfield(app.CoregEstimationPanel, 'numeric');
            app.edit_bias_reg.Value = 0.0001;
            app.FWHMmmLabel = uilabel(app.CoregEstimationPanel);
            app.FWHMmmLabel.Text = 'FWHM偏差 (mm)';
            app.edit_bias_fwhm = uieditfield(app.CoregEstimationPanel, 'numeric');
            app.edit_bias_fwhm.Value = 60;
            app.Label_11 = uilabel(app.CoregEstimationPanel);
            app.Label_11.Text = '平滑度';
            app.edit_smoothness = uieditfield(app.CoregEstimationPanel, 'numeric');
            app.Label_12 = uilabel(app.CoregEstimationPanel);
            app.Label_12.Text = '采样距离';
            app.edit_sampling_dist = uieditfield(app.CoregEstimationPanel, 'numeric');
            app.edit_sampling_dist.Value = 3;
            app.InterpolationLabel = uilabel(app.CoregEstimationPanel);
            app.InterpolationLabel.Text = '插值方法';
            app.dropdown_interp = uidropdown(app.CoregEstimationPanel);
            app.dropdown_interp.Items = {'Nearest Neighbour', 'Trlinear', '2nd Degree B-Spline', '3rd Degree B-Spline', '4th Degree B-Spline'};
            app.dropdown_interp.Value = 'Nearest Neighbour';
            app.VoxelsizesmmLabel = uilabel(app.CoregEstimationPanel);
            app.VoxelsizesmmLabel.Text = '像素大小 (mm)';
            app.edit_voxel_size1 = uieditfield(app.CoregEstimationPanel, 'numeric');
            app.edit_voxel_size1.Value = 2;
            app.Label_18 = uilabel(app.CoregEstimationPanel);
            app.Label_18.Text = '文件名前缀';
            app.edit_prefix = uieditfield(app.CoregEstimationPanel, 'text');
            app.edit_prefix.Value = 'w';
            app.CoregReslicePanel = uipanel(app.Tab_2);
            app.CoregReslicePanel.Title = 'CoregisterTab';
            app.btn_browse_spm_2 = uibutton(app.CoregReslicePanel, 'push');
            app.btn_browse_spm_2.ButtonPushedFcn = createCallbackFcn(app, @btn_browse_spm_2ButtonPushed2, true);
            app.btn_browse_spm_2.Text = '浏览..';
            app.btn_browse_reference_image = uibutton(app.CoregReslicePanel, 'push');
            app.btn_browse_reference_image.ButtonPushedFcn = createCallbackFcn(app, @btn_browse_reference_imageButtonPushed2, true);
            app.btn_browse_reference_image.Text = '浏览...';
            app.btn_browse_source_image = uibutton(app.CoregReslicePanel, 'push');
            app.btn_browse_source_image.ButtonPushedFcn = createCallbackFcn(app, @btn_browse_source_imageButtonPushed2, true);
            app.btn_browse_source_image.Text = '浏览...';
            app.btn_browse_other_images = uibutton(app.CoregReslicePanel, 'push');
            app.btn_browse_other_images.Text = '浏览...';
            app.btn_run_coregister = uibutton(app.CoregReslicePanel, 'push');
            app.btn_run_coregister.ButtonPushedFcn = createCallbackFcn(app, @btn_run_coregisterButtonPushed2, true);
            app.btn_run_coregister.Text = 'Coregister';
            app.edit_separation2 = uieditfield(app.CoregReslicePanel, 'numeric');
            app.edit_separation2.Value = 2;
            app.edit_hist_smooth2 = uieditfield(app.CoregReslicePanel, 'numeric');
            app.edit_hist_smooth2.Value = 7;
            app.ObjectiveFunctionLabel = uilabel(app.CoregReslicePanel);
            app.ObjectiveFunctionLabel.Text = '目标函数';
            app.dropdown_obj_func = uidropdown(app.CoregReslicePanel);
            app.dropdown_obj_func.Items = {'mutual', 'nmi', 'cc'};
            app.dropdown_obj_func.Value = 'nmi';
            app.SeparationLabel = uilabel(app.CoregReslicePanel);
            app.SeparationLabel.Text = '分选';
            app.edit_separation1 = uieditfield(app.CoregReslicePanel, 'numeric');
            app.edit_separation1.Value = 4;
            app.InterpolationLabel_2 = uilabel(app.CoregReslicePanel);
            app.InterpolationLabel_2.Text = '插值';
            app.dropdown_coreg_interp = uidropdown(app.CoregReslicePanel);
            app.dropdown_coreg_interp.Items = {'Nearest Neighbour', 'Trlinear', '2nd Degree B-Spline', '3rd Degree B-Spline', '4th Degree B-Spline'};
            app.dropdown_coreg_interp.Value = 'Nearest Neighbour';
            app.SPM12Label_2 = uilabel(app.CoregReslicePanel);
            app.SPM12Label_2.Text = 'SPM12';
            app.edit_spm_2 = uieditfield(app.CoregReslicePanel, 'text');
            app.ReferenceImageLabel = uilabel(app.CoregReslicePanel);
            app.ReferenceImageLabel.Text = 'Reference Image';
            app.edit_reference_image = uieditfield(app.CoregReslicePanel, 'text');
            app.InterpolationLabel_3 = uilabel(app.CoregReslicePanel);
            app.InterpolationLabel_3.Text = '掩模';
            app.dropdown_masking = uidropdown(app.CoregReslicePanel);
            app.dropdown_masking.Items = {'Dont mask images', 'Mask images'};
            app.dropdown_masking.Value = 'Dont mask images';
            app.OtherImagesLabel = uilabel(app.CoregReslicePanel);
            app.OtherImagesLabel.Text = 'Other Images';
            app.edit_other_images = uieditfield(app.CoregReslicePanel, 'text');
            app.edit_source_image = uieditfield(app.CoregReslicePanel, 'text');
            app.SourceImageLabel = uilabel(app.CoregReslicePanel);
            app.SourceImageLabel.Text = 'Source Image';
            app.HistogramSmoothingLabel = uilabel(app.CoregReslicePanel);
            app.HistogramSmoothingLabel.Text = '直方图平滑';
            app.edit_hist_smooth1 = uieditfield(app.CoregReslicePanel, 'numeric');
            app.edit_hist_smooth1.Value = 7;
            app.InterpolationLabel_4 = uilabel(app.CoregReslicePanel);
            app.InterpolationLabel_4.Text = 'Wrapping';
            app.dropdown_wrapping = uidropdown(app.CoregReslicePanel);
            app.dropdown_wrapping.Items = {'No wrap', 'Wrap'};
            app.dropdown_wrapping.Value = 'No wrap';
            app.Label_19 = uilabel(app.CoregReslicePanel);
            app.Label_19.Text = '文件名前缀';
            app.edit_coreg_prefix = uieditfield(app.CoregReslicePanel, 'text');
            app.edit_coreg_prefix.Value = 'r';
            app.Label_16 = uilabel(app.Tab_2);
            app.Label_16.Text = '运行日志';
            app.textarea_log = uitextarea(app.Tab_2);
            app.Label_17 = uilabel(app.Tab_2);
            app.Label_17.Text = '配准界面';
            app.Image2 = uiimage(app.Tab_2);
            app.Image2.ImageSource = fullfile(pathToMLAPP, '139047523_p0.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MedImagePro

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end